classdef CreateBIDsTable < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SesOKButton                     matlab.ui.control.Button
        ExpOKButton                     matlab.ui.control.Button
        ParticipantsListBox             matlab.ui.control.ListBox
        SelectExperimentstoIncludeLabel_3  matlab.ui.control.Label
        SessionsListBox                 matlab.ui.control.ListBox
        SelectExperimentstoIncludeLabel_2  matlab.ui.control.Label
        ExperimentsListBox              matlab.ui.control.ListBox
        SelectExperimentstoIncludeLabel  matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
        SelectDataFormatDropDown        matlab.ui.control.DropDown
        SelectPreprocessingformatLabel  matlab.ui.control.Label
        ExpDirLabel                     matlab.ui.control.Label
        SelectExperimentDirectoryButton  matlab.ui.control.Button
        CreateBIDsParticipantGroupTableLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectExperimentDirectoryButton
        function SelectExperimentDirectoryButtonPushed(app, event)
            % Select base directory containing Experiments folders 
            % should be named Experiments
            BIDsDir = uigetdir();
            app.ExpDirLabel.Text=[BIDsDir,'/'];
            app.SelectDataFormatDropDown.Enable="on";
            
        end

        % Value changed function: SelectDataFormatDropDown
        function SelectDataFormatDropDownValueChanged(app, event)
            value = app.SelectDataFormatDropDown.Value;
            app.ExperimentsListBox.Enable="on";
            if strcmpi(value,'fMRIPrep') 
                %Populate the experiments directory
                folderNames=getFolderAndFileNames(app.ExpDirLabel.Text);
                if ~isempty(folderNames)
                    app.ExperimentsListBox.Items=folderNames;
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CreateBIDsParticipantGroupTableLabel = uilabel(app.UIFigure);
            app.CreateBIDsParticipantGroupTableLabel.Text = {'Create BIDs Participant '; 'Group Table'};
            app.SelectExperimentDirectoryButton = uibutton(app.UIFigure, 'push');
            app.SelectExperimentDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @SelectExperimentDirectoryButtonPushed, true);
            app.SelectExperimentDirectoryButton.Text = {'Select Experiment '; ' Directory'};
            app.ExpDirLabel = uilabel(app.UIFigure);
            app.ExpDirLabel.Visible = 'off';
            app.ExpDirLabel.Text = '';
            app.SelectPreprocessingformatLabel = uilabel(app.UIFigure);
            app.SelectPreprocessingformatLabel.Enable = 'off';
            app.SelectPreprocessingformatLabel.Text = 'Select Data Format';
            app.SelectDataFormatDropDown = uidropdown(app.UIFigure);
            app.SelectDataFormatDropDown.Items = {'Select One', 'fMRIPrep', 'AFNI', 'Brain Voyager'};
            app.SelectDataFormatDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectDataFormatDropDownValueChanged, true);
            app.SelectDataFormatDropDown.Enable = 'off';
            app.SelectDataFormatDropDown.Value = 'Select One';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'Input the directory of the fMRIPrep output. This function extracts'; 'information regarding particpants, tasks, runs filenames and directories etc...'; 'for the contained files. Output in a number of usable; however, only the '; 'BIDsTable format is used for the subsequent further analyses.'; 'ExpDir Folder structure should be as follows: * = optional.'; 'ExpDir/[experiments]/fmriprep/[sub-ID]/[ses-0#]*/[anat,func]/files'; ''; 'Logic of function: divide file properties into types,folders and properties,'; 'types consist of behavior, anat, or func, folders contain experiment, subID,'; 'sesion and file type. Properties consist of any infor contained in file'; 'name.'; 'All this info will be automatically compiled into a large table for each '; 'file type and then relevant attribuites will be manually selected to construct'; 'the fMRIPrep_Table. This table will contain the local directory and relevant'; 'properties for each analysis unit (fMRI run).'};
            app.SelectExperimentstoIncludeLabel = uilabel(app.UIFigure);
            app.SelectExperimentstoIncludeLabel.Enable = 'off';
            app.SelectExperimentstoIncludeLabel.Text = 'Select Experiments to Include:';
            app.ExperimentsListBox = uilistbox(app.UIFigure);
            app.ExperimentsListBox.Items = {};
            app.ExperimentsListBox.Enable = 'off';
            app.ExperimentsListBox.Value = {};
            app.SelectExperimentstoIncludeLabel_2 = uilabel(app.UIFigure);
            app.SelectExperimentstoIncludeLabel_2.Enable = 'off';
            app.SelectExperimentstoIncludeLabel_2.Text = 'Select Sessions to Include:';
            app.SessionsListBox = uilistbox(app.UIFigure);
            app.SessionsListBox.Items = {};
            app.SessionsListBox.Enable = 'off';
            app.SessionsListBox.Value = {};
            app.SelectExperimentstoIncludeLabel_3 = uilabel(app.UIFigure);
            app.SelectExperimentstoIncludeLabel_3.Enable = 'off';
            app.SelectExperimentstoIncludeLabel_3.Text = 'Select Partipants to EXCLUDE:';
            app.ParticipantsListBox = uilistbox(app.UIFigure);
            app.ParticipantsListBox.Items = {};
            app.ParticipantsListBox.Enable = 'off';
            app.ParticipantsListBox.Value = {};
            app.ExpOKButton = uibutton(app.UIFigure, 'push');
            app.ExpOKButton.Text = 'OK';
            app.SesOKButton = uibutton(app.UIFigure, 'push');
            app.SesOKButton.Text = 'OK';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CreateBIDsTable

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end