classdef app_CompileAndDataPointPlot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        CompileDataTab                matlab.ui.container.Tab
        TableRow                      matlab.ui.control.Spinner
        SetDataParametersPanel        matlab.ui.container.Panel
        SaveFormatSwitch              matlab.ui.control.Switch
        SaveFormatSwitchLabel         matlab.ui.control.Label
        SaveTypeSwitch                matlab.ui.control.Switch
        SaveTypeSwitchLabel           matlab.ui.control.Label
        LoadFormatDropDown            matlab.ui.control.DropDown
        LoadFormatDropDownLabel       matlab.ui.control.Label
        DataTypeDropDown              matlab.ui.control.DropDown
        DataTypeDropDownLabel         matlab.ui.control.Label
        DefineDatasetsPanel           matlab.ui.container.Panel
        VariableDropDown              matlab.ui.control.DropDown
        VariableDropDownLabel         matlab.ui.control.Label
        ParcellationDropDown          matlab.ui.control.DropDown
        ParcellationDropDownLabel     matlab.ui.control.Label
        NameDropDown                  matlab.ui.control.DropDown
        NameDropDownLabel             matlab.ui.control.Label
        TypeDropDown                  matlab.ui.control.DropDown
        TypeDropDownLabel             matlab.ui.control.Label
        SubjectorRunPanel             matlab.ui.container.Panel
        SetButton                     matlab.ui.control.Button
        Switch                        matlab.ui.control.ToggleSwitch
        SelectdirectoryandparticipanttablePanel  matlab.ui.container.Panel
        ResetButton                   matlab.ui.control.Button
        ExpTableName                  matlab.ui.control.EditField
        ExpDirEditField               matlab.ui.control.EditField
        SelectExperimentTableButton   matlab.ui.control.Button
        GetExperimentDirectoryButton  matlab.ui.control.Button
        DatasetstocompilePanel        matlab.ui.container.Panel
        EnterDataLabelEditField       matlab.ui.control.EditField
        EnterdatalabelEditFieldLabel  matlab.ui.control.Label
        StartNewButton                matlab.ui.control.Button
        CompileDataButton             matlab.ui.control.Button
        AddMoreButton                 matlab.ui.control.Button
        UITable                       matlab.ui.control.Table
        GraphDataTab                  matlab.ui.container.Tab
        ResetPlotTableButton          matlab.ui.control.Button
        ResetDataTableButton          matlab.ui.control.Button
        SaveCurrentPlotButton         matlab.ui.control.Button
        DataTablePanel                matlab.ui.container.Panel
        DataTable                     matlab.ui.control.Table
        ResetPlotButton               matlab.ui.control.Button
        GeneratePlotButton            matlab.ui.control.Button
        TabGroup2                     matlab.ui.container.TabGroup
        GeneralTab                    matlab.ui.container.Tab
        XLinePositionSpinner          matlab.ui.control.Spinner
        XLinePositionSpinnerLabel     matlab.ui.control.Label
        AddXlineCheckBox              matlab.ui.control.CheckBox
        LegendPositionDropDown        matlab.ui.control.DropDown
        LegendPositionDropDownLabel   matlab.ui.control.Label
        BootstrapWidthSpinner         matlab.ui.control.Spinner
        BootstrapWidthSpinnerLabel    matlab.ui.control.Label
        BootstrapDistributionLabel    matlab.ui.control.Label
        BootstrapCheckBox             matlab.ui.control.CheckBox
        IncludeLegendCheckBox         matlab.ui.control.CheckBox
        LevelstoStaggerSpinner        matlab.ui.control.Spinner
        LevelstoStaggerSpinnerLabel   matlab.ui.control.Label
        RotationDegreeSpinner         matlab.ui.control.Spinner
        RotationDegreeSpinnerLabel    matlab.ui.control.Label
        ItalicsCheckBox               matlab.ui.control.CheckBox
        BoldCheckBox                  matlab.ui.control.CheckBox
        InterpreterDropDown           matlab.ui.control.DropDown
        InterpreterDropDownLabel      matlab.ui.control.Label
        FontDropDown                  matlab.ui.control.DropDown
        FontDropDownLabel             matlab.ui.control.Label
        FontsizeSpinner               matlab.ui.control.Spinner
        FontsizeSpinnerLabel          matlab.ui.control.Label
        XLabelpositionDropDown        matlab.ui.control.DropDown
        XLabelpositionDropDownLabel   matlab.ui.control.Label
        ShareAcrossGroupCheckBox      matlab.ui.control.CheckBox
        LabelHandeling                matlab.ui.control.Label
        DataWidthSpinner              matlab.ui.control.Spinner
        DataWidthSpinnerLabel         matlab.ui.control.Label
        PlotAreasSwitch               matlab.ui.control.Switch
        PlotAreasSwitchLabel          matlab.ui.control.Label
        XshiftSpinner                 matlab.ui.control.Spinner
        XshiftSpinnerLabel            matlab.ui.control.Label
        EnterpermutationsEditField    matlab.ui.control.NumericEditField
        EnterpermutationsEditFieldLabel  matlab.ui.control.Label
        ToLabel                       matlab.ui.control.Label
        YMaxEditField                 matlab.ui.control.NumericEditField
        FromLabel                     matlab.ui.control.Label
        YMinEditField                 matlab.ui.control.NumericEditField
        YRangeEditFieldLabel          matlab.ui.control.Label
        ColorEtcTab                   matlab.ui.container.Tab
        TearDropColorBSpinner         matlab.ui.control.Spinner
        BSpinnerLabel_4               matlab.ui.control.Label
        TearDropColorGSpinner         matlab.ui.control.Spinner
        GSpinnerLabel_4               matlab.ui.control.Label
        TearDropColorRSpinner         matlab.ui.control.Spinner
        RSpinnerLabel_4               matlab.ui.control.Label
        TearDropColorGraySpinner      matlab.ui.control.Spinner
        GrayLabel_3                   matlab.ui.control.Label
        TearDropColorDropDown         matlab.ui.control.DropDown
        ColorDropDown_2Label_2        matlab.ui.control.Label
        BootstrapteardropplotLabel    matlab.ui.control.Label
        BootstrapTransparencySpinner  matlab.ui.control.Spinner
        TransparencySpinnerLabel      matlab.ui.control.Label
        FillMarkerCheckBox            matlab.ui.control.CheckBox
        MeanMarkerColorGraySpinner    matlab.ui.control.Spinner
        GrayLabel_2                   matlab.ui.control.Label
        FillMeanMarkerCheckBox        matlab.ui.control.CheckBox
        MarkerColorGraySpinner        matlab.ui.control.Spinner
        GrayLabel                     matlab.ui.control.Label
        MarkerColorBSpinner           matlab.ui.control.Spinner
        BSpinnerLabel_3               matlab.ui.control.Label
        MarkerColorGSpinner           matlab.ui.control.Spinner
        GSpinnerLabel_3               matlab.ui.control.Label
        MarkerColorRSpinner           matlab.ui.control.Spinner
        RSpinnerLabel_3               matlab.ui.control.Label
        ColorDropDown                 matlab.ui.control.DropDown
        ColorDropDownLabel            matlab.ui.control.Label
        MarkerTypeDropDown            matlab.ui.control.DropDown
        TypeDropDown_2Label           matlab.ui.control.Label
        MarkerSizeSpinner             matlab.ui.control.Spinner
        SizeSpinnerLabel_3            matlab.ui.control.Label
        EditColorLabel                matlab.ui.control.Label
        DataPointMarkerLabel          matlab.ui.control.Label
        MeanMarkerColorBSpinner       matlab.ui.control.Spinner
        BSpinnerLabel_2               matlab.ui.control.Label
        MeanMarkerColorGSpinner       matlab.ui.control.Spinner
        GSpinnerLabel_2               matlab.ui.control.Label
        MeanMarkerColorRSpinner       matlab.ui.control.Spinner
        RSpinnerLabel_2               matlab.ui.control.Label
        MeanColorDropDown             matlab.ui.control.DropDown
        ColorDropDown_2Label          matlab.ui.control.Label
        MeanTypeDropDown              matlab.ui.control.DropDown
        TypeDropDown_3Label           matlab.ui.control.Label
        MeanMarkerSizeSpinner         matlab.ui.control.Spinner
        SizeSpinnerLabel_2            matlab.ui.control.Label
        EditMeanColorLabel            matlab.ui.control.Label
        MeanPointMarkerLabel          matlab.ui.control.Label
        StatReportTab                 matlab.ui.container.Tab
        PlotTablePanel                matlab.ui.container.Panel
        UITable2Graph                 matlab.ui.control.Table
        SelectAndLabelDataPanel       matlab.ui.container.Panel
        AddAllButton                  matlab.ui.control.Button
        AddGroupButton                matlab.ui.control.Button
        StartNewGroupButton           matlab.ui.control.Button
        XDataLabelEditField           matlab.ui.control.EditField
        XDataLabelEditFieldLabel      matlab.ui.control.Label
        GroupNameEditField            matlab.ui.control.EditField
        GroupNameEditFieldLabel       matlab.ui.control.Label
        AddDataButton                 matlab.ui.control.Button
        DatatoplotDropDown            matlab.ui.control.DropDown
        DatatoplotDropDownLabel       matlab.ui.control.Label
        CompiledSetDropDown           matlab.ui.control.DropDown
        CompiledSetDropDownLabel      matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GetExperimentDirectoryButton
        function GetExperimentDirectoryButtonPushed(app, event)
            Ind=app.TableRow.Value;
            ExperimentsDir=uigetdir('/','Select Experiments Directory');
            ExperimentsDir=[ExperimentsDir,'/'];
            ExperimentsDir=strrep(ExperimentsDir,'\','/');
            tempDir=strrep(ExperimentsDir,'/Experiments/','/');
            slashInd=strfind(tempDir,'/');
            ExpName=tempDir(1,slashInd(1,end-1):slashInd(1,end));
            app.UIFigure.UserData.ExpName=ExpName;
            app.UIFigure.UserData.ExperimentsDir=ExperimentsDir;
            app.ExpDirEditField.Value=ExperimentsDir;
            app.GetExperimentDirectoryButton.Enable='Off';
        end

        % Button pushed function: SelectExperimentTableButton
        function SelectExperimentTableButtonPushed(app, event)
            Ind=app.TableRow.Value;
            ExpName=app.UIFigure.UserData.ExpName;
            [~,fmriprep_table_names]=getFolderAndFileNames(['fmriprep_table',ExpName]);
            SingleSelect=1;
            fmriprep_table_name=uiNameSelect([{'none- make table'};fmriprep_table_names],'Select fmriprep_table used:',SingleSelect,1);
            fmriprep_table_name=strrep(fmriprep_table_name,'.mat','');
            app.UIFigure.UserData.fmriprep_table_name=fmriprep_table_name;
            app.ExpTableName.Value=fmriprep_table_name;
            tempload=load(['fmriprep_table',ExpName,fmriprep_table_name],'fmriprep_table');            
            app.UIFigure.UserData.fmriprep_table=tempload.fmriprep_table;
            app.SelectExperimentTableButton.Enable='Off';
            app.Switch.Enable='On';
            app.SetButton.Enable='on';
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.GetExperimentDirectoryButton.Enable='On';
            app.SelectExperimentTableButton.Enable='On';
        end

        % Button pushed function: SetButton
        function SetButtonPushed(app, event)
            Ind=app.TableRow.Value;
            app.UIFigure.UserData.SubjectOrRun=app.Switch.Value;
            TotalRuns=height(app.UIFigure.UserData.fmriprep_table);
            dataInd=find(app.UIFigure.UserData.fmriprep_table.run==1)';
            if strcmpi(app.UIFigure.UserData.SubjectOrRun,'Subject')
                useIndicies=dataInd;
            else
                useIndicies=[1:TotalRuns];
            end
            AllAnalysisTypes=[];
            for i = useIndicies
                dirName=[app.ExpDirEditField.Value,app.UIFigure.UserData.fmriprep_table.matDir{i,1}];
                [folderNames]=getFolderAndFileNames(dirName);
                AllAnalysisTypes=unique([AllAnalysisTypes;folderNames]);
            end
            app.TypeDropDown.Enable='on';
            app.TypeDropDown.Items=[{''},AllAnalysisTypes(:)'] ; 
            app.SetButton.Enable='off';
        end

        % Value changed function: TypeDropDown
        function TypeDropDownValueChanged(app, event)
            Ind=app.TableRow.Value;
            value = app.TypeDropDown.Value;
            if ~strcmpi(value,'Undefined') && ~strcmpi(value,'')
                app.UIFigure.UserData.AnalysisType=value; 
                TotalRuns=height(app.UIFigure.UserData.fmriprep_table);
                dataInd=find(app.UIFigure.UserData.fmriprep_table.run==1)';
                if strcmpi(app.UIFigure.UserData.SubjectOrRun,'Subject')
                    useIndicies=dataInd;
                else
                    useIndicies=[1:TotalRuns];
                end
                AllAnalysisNames=[];
                for i = useIndicies
                    dirName=[app.ExpDirEditField.Value,app.UIFigure.UserData.fmriprep_table.matDir{i,1},'/',app.UIFigure.UserData.AnalysisType,'/'];
                    [folderNames]=getFolderAndFileNames(dirName);
                    AllAnalysisNames=unique([AllAnalysisNames;folderNames]);
                end
                app.NameDropDown.Enable='on';
                app.ParcellationDropDown.Enable='on';
                app.VariableDropDown.Enable='on';
                app.NameDropDown.Items=[{''},AllAnalysisNames(:)']; 
                app.NameDropDown.Value=app.NameDropDown.Items{1,1};
            end
        end

        % Value changed function: NameDropDown
        function NameDropDownValueChanged(app, event)
            Ind=app.TableRow.Value;
            value = app.NameDropDown.Value;
            if ~strcmpi(value,'Undefined') && ~strcmpi(value,'')
                app.UIFigure.UserData.AnalysisName=value;
                [app.UIFigure.UserData.filePaths] = BIDsDirSearch(app.ExpDirEditField.Value,app.UIFigure.UserData.fmriprep_table,'SubjectOrRun',app.UIFigure.UserData.SubjectOrRun,'AnalysisType',app.UIFigure.UserData.AnalysisType,'AnalysisName',app.UIFigure.UserData.AnalysisName);           
                app.ParcellationDropDown.Items=[{''},app.UIFigure.UserData.filePaths.Properties.VariableNames(:)'];
                app.ParcellationDropDown.Value=app.ParcellationDropDown.Items{1,1};
            end
        end

        % Value changed function: ParcellationDropDown
        function ParcellationDropDownValueChanged(app, event)
            Ind=app.TableRow.Value;
            value = app.ParcellationDropDown.Value;
            if ~strcmpi(value,'Undefined') && ~strcmpi(value,'')
                app.UIFigure.UserData.ParcelName=value;
                if length(app.ParcellationDropDown.Items) > 1
                    app.UIFigure.UserData.DataType='ByParcellation';
                    app.DataTypeDropDown.Value='ByParcellation';
                else
                    app.DataTypeDropDown.Enable='On';
                end   
                LoadVariableName=[];
                for i = 1:size(app.UIFigure.UserData.filePaths.(value),1)
                    try
                        variableInfo = who('-file', app.UIFigure.UserData.filePaths.(value){i,1});
                    catch
                        continue
                    end
                    LoadVariableName=unique([LoadVariableName;variableInfo]);
                end
                app.VariableDropDown.Items=[{''},LoadVariableName(:)'];  
                app.VariableDropDown.Value=app.VariableDropDown.Items{1,1};
            end
        end

        % Value changed function: VariableDropDown
        function VariableDropDownValueChanged(app, event)
            Ind=app.TableRow.Value;
            value = app.VariableDropDown.Value;
            if ~strcmpi(value,'Undefined') && ~strcmpi(value,'')
                app.AddMoreButton.Enable='On';
                app.EnterDataLabelEditField.Enable='On';
                app.EnterDataLabelEditField.Value=[app.TypeDropDown.Value,' - ',app.NameDropDown.Value,' - ',app.ParcellationDropDown.Value,' - ',value];
                for i = 1:size(app.UIFigure.UserData.filePaths.(app.UIFigure.UserData.ParcelName),1)
                    try
                        tempLoad=load(app.UIFigure.UserData.filePaths.(app.UIFigure.UserData.ParcelName){i,1},value);
                        tempVar=tempLoad.(value);
                        if istable(tempVar)
                            app.LoadFormatDropDown.Value='Table';
                            app.SaveFormatSwitch.Enable='On';
                            if any(ismember(size(tempVar),1))
                                app.UIFigure.UserData.ndim=1;  
                                app.SaveTypeSwitch.Value='Vertical';
                                app.SaveTypeSwitch.Enable='Off';
                            else
                                app.UIFigure.UserData.ndim=2;
                                app.SaveTypeSwitch.Enable='On';
                            end
                        elseif iscell(tempVar)                       
                            app.LoadFormatDropDown.Value='Cell';
                            app.SaveFormatSwitch.Value='Array';
                            app.SaveFormatSwitch.Enable='Off';
                            if ndims(tempVar)>2
                                app.UIFigure.UserData.ndim=ndims(tempVar);
                                app.SaveTypeSwitch.Value='Matrix';
                                app.SaveTypeSwitch.Enable='Off';
                            elseif ndims(tempVar)==2
                                app.UIFigure.UserData.ndim=2;
                                app.SaveTypeSwitch.Enable='On';
                            else  
                                app.SaveTypeSwitch.Value='Vertical';
                                app.UIFigure.UserData.ndim=1;
                                app.SaveTypeSwitch.Enable='Off';
                            end
                        else
                            app.LoadFormatDropDown.Value='Array';
                            app.SaveFormatSwitch.Value='Array';
                            app.SaveFormatSwitch.Enable='Off';
                            if ndims(tempVar)>2
                                app.UIFigure.UserData.ndim=ndims(tempVar);
                                app.SaveTypeSwitch.Value='Matrix';
                                app.SaveTypeSwitch.Enable='Off';
                            elseif ndims(tempVar)==2
                                app.UIFigure.UserData.ndim=2;
                                app.SaveTypeSwitch.Enable='On';
                            else  
                                app.UIFigure.UserData.ndim=1;
                                app.SaveTypeSwitch.Value='Vertical';
                                app.SaveTypeSwitch.Enable='Off';
                            end
                        end
                        break
                    catch
                        continue
                    end
                end    
            end
        end

        % Button pushed function: AddMoreButton
        function AddMoreButtonPushed(app, event)
            Ind=app.TableRow.Value;
            app.StartNewButton.Enable='on';
            TableNames={'CompileLabel','ExperimentsDir','fmriprep_table_name','fmriprep_table','SubjectOrRun','AnalysisType','AnalysisName',...
                'ParcelName','LoadVarName','LoadVarFormat','VertOrMat','TableOrArray','DataType','ndim'};
            CompileLabel={app.EnterDataLabelEditField.Value};
            ExperimentsDir={app.ExpDirEditField.Value};
            fmriprep_table_name={app.ExpTableName.Value};
            fmriprep_table={app.UIFigure.UserData.fmriprep_table};
            SubjectOrRun={app.UIFigure.UserData.SubjectOrRun};
            AnalysisType={app.TypeDropDown.Value};
            AnalysisName={app.NameDropDown.Value};
            ParcelName={app.ParcellationDropDown.Value};
            LoadVarName={app.VariableDropDown.Value};
            LoadVarFormat={app.LoadFormatDropDown.Value};            
            VertOrMat={app.SaveTypeSwitch.Value};
            TableOrArray={app.SaveFormatSwitch.Value};
            DataType={app.DataTypeDropDown.Value};
            ndim={app.UIFigure.UserData.ndim};
            app.UITable.ColumnName=TableNames;
            T=cell2table([CompileLabel,ExperimentsDir,fmriprep_table_name,fmriprep_table,SubjectOrRun,AnalysisType,AnalysisName,...
                ParcelName,LoadVarName,LoadVarFormat,VertOrMat,TableOrArray,DataType,ndim],'VariableNames',TableNames);
            if Ind == 1
                app.UITable.Data=T;
            else
                app.UITable.Data(Ind,:)=T;
            end
        end

        % Button pushed function: StartNewButton
        function StartNewButtonPushed(app, event)
            app.TableRow.Value=app.TableRow.Value+1;
            app.AddMoreButton.Enable='off';
            app.Switch.Enable='on';
            app.SetButton.Enable='on';
            app.StartNewButton.Enable='off';
            app.TypeDropDown.Enable='off';
            app.TypeDropDown.Items={''};
            app.TypeDropDown.Value='';
            app.NameDropDown.Enable='off';
            app.NameDropDown.Items={''};
            app.NameDropDown.Value='';            
            app.ParcellationDropDown.Enable='off';
            app.ParcellationDropDown.Items={''};
            app.ParcellationDropDown.Value='';            
            app.VariableDropDown.Enable='off';
            app.VariableDropDown.Items={''};
            app.VariableDropDown.Value='';            
            app.DataTypeDropDown.Enable='off';
            app.SaveTypeSwitch.Enable='off';
            app.SaveFormatSwitch.Enable='off';
            app.EnterDataLabelEditField.Value='';
        end

        % Button pushed function: CompileDataButton
        function CompileDataButtonPushed(app, event)
            UseTable=app.UITable.Data;
            numCompiles=height(UseTable);
            compiledData=cell(numCompiles,2); % {compiledData, compiled_fmriprep_table, selectind, DataLabels}
            for i = 1:numCompiles
                if UseTable.ndim(i,1)==1    
                    [compiledData{i,1},compiledData{i,2},compiledData{i,3},compiledData{i,4}] = ...
                        Compile1D(UseTable.ExperimentsDir{i,1},UseTable.fmriprep_table{i,1},...
                        'AnalysisType',UseTable.AnalysisType{i,1},...
                        'AnalysisName',UseTable.AnalysisName{i,1},...
                        'LoadVarName',UseTable.LoadVarName{i,1},...
                        'LoadVarFormat',UseTable.LoadVarFormat{i,1},...
                        'DataType',UseTable.DataType{i,1},...
                        'SubjectOrRun',UseTable.SubjectOrRun{i,1},...
                        'ParcelName',UseTable.ParcelName{i,1},...
                        'TableOrArray',UseTable.TableOrArray{i,1});
                elseif UseTable.ndim(i,1)==2
                    [compiledData{i,1},compiledData{i,2},compiledData{i,3},compiledData{i,4}] = ...
                        Compile2D(UseTable.ExperimentsDir{i,1},UseTable.fmriprep_table{i,1},...
                        'AnalysisType',UseTable.AnalysisType{i,1},...
                        'AnalysisName',UseTable.AnalysisName{i,1},...
                        'LoadVarName',UseTable.LoadVarName{i,1},...
                        'LoadVarFormat',UseTable.LoadVarFormat{i,1},...
                        'DataType',UseTable.DataType{i,1},...
                        'SubjectOrRun',UseTable.SubjectOrRun{i,1},...
                        'ParcelName',UseTable.ParcelName{i,1},...
                        'TableOrArray',UseTable.TableOrArray{i,1},...
                        'VertOrMat',UseTable.VertOrMat{i,1}); 
                else
                    [compiledData{i,1},compiledData{i,2},compiledData{i,3},compiledData{i,4}] = ...
                        CompileND(UseTable.ExperimentsDir{i,1},UseTable.fmriprep_table{i,1},...
                        'AnalysisType',UseTable.AnalysisType{i,1},...
                        'AnalysisName',UseTable.AnalysisName{i,1},...
                        'LoadVarName',UseTable.LoadVarName{i,1},...
                        'LoadVarFormat',UseTable.LoadVarFormat{i,1},...
                        'DataType',UseTable.DataType{i,1},...
                        'SubjectOrRun',UseTable.SubjectOrRun{i,1},...
                        'ParcelName',UseTable.ParcelName{i,1});     
                end
            end
            CompiledDataTable=[cell2table(compiledData,'VariableNames',{'Data','compiled_fmriprep_table','selectind','DataLabels'}),UseTable];
            app.UIFigure.UserData.CompiledDataTable=CompiledDataTable;
            %Disable Compile Data Tab
            app.AddMoreButton.Enable='off';
            app.Switch.Enable='off';
            app.SetButton.Enable='off';
            app.TypeDropDown.Enable='off';
            app.TypeDropDown.Items={''};
            app.TypeDropDown.Value='';
            app.NameDropDown.Enable='off';
            app.NameDropDown.Items={''};
            app.NameDropDown.Value='';            
            app.ParcellationDropDown.Enable='off';
            app.ParcellationDropDown.Items={''};
            app.ParcellationDropDown.Value='';            
            app.VariableDropDown.Enable='off';
            app.VariableDropDown.Items={''};
            app.VariableDropDown.Value='';            
            app.DataTypeDropDown.Enable='off';
            app.SaveTypeSwitch.Enable='off';
            app.SaveFormatSwitch.Enable='off';
            app.EnterDataLabelEditField.Value=''; 
            app.StartNewButton.Enable='Off';
            app.CompiledSetDropDown.Items=[{''},CompiledDataTable.CompileLabel(:)'];   
            app.FontDropDown.Items=listfonts;
            app.FontDropDown.Value='Courier New';
            app.UIFigure.UserData.GroupCount=0;
        end

        % Value changed function: CompiledSetDropDown
        function CompiledSetDropDownValueChanged(app, event)
            value = app.CompiledSetDropDown.Value;
            app.GroupNameEditField.Value=value;
            app.UIFigure.UserData.UseRowInd=ismember(app.UIFigure.UserData.CompiledDataTable.CompileLabel,value);
            UseTableRow=app.UIFigure.UserData.CompiledDataTable(app.UIFigure.UserData.UseRowInd==1,:);
            if istable(UseTableRow.Data{1,1})
                app.DatatoplotDropDown.Items = [{''},{'Blank'},UseTableRow.Data{1,1}.Properties.VariableNames]';
            elseif isstruct(UseTableRow.DataLabels(1,1))
                if isfield(UseTableRow.DataLabels(1,1),'labelPairs2')
                    app.DatatoplotDropDown.Items = [{''},{'Blank'},UseTableRow.DataLabels(1,1).labelPairs2']';
                else
                    app.DatatoplotDropDown.Items = [{''},{'Blank'},UseTableRow.DataLabels(1,1).ROILabels']';
                end
            else
               app.DatatoplotDropDown.Items = [{''},{'Blank'},UseTableRow.DataLabels]'; 
            end
        end

        % Value changed function: DatatoplotDropDown
        function DatatoplotDropDownValueChanged(app, event)
            value = app.DatatoplotDropDown.Value;
            app.XDataLabelEditField.Value=value;
        end

        % Button pushed function: StartNewGroupButton
        function StartNewGroupButtonPushed(app, event)
            %Set counters
            app.UIFigure.UserData.PlotInfo=struct;
            app.StartNewGroupButton.Enable='Off';
            app.UIFigure.UserData.MemberCount=1; %(Re)Start member count from 1
            app.UIFigure.UserData.GroupCount=app.UIFigure.UserData.GroupCount+1;%Count to next group
            app.AddDataButton.Enable='On';
            app.AddAllButton.Enable='On';
            app.CompiledSetDropDown.Enable='On';
            app.DatatoplotDropDown.Enable='On';
            if app.UIFigure.UserData.GroupCount > 1
                app.XshiftSpinner.Enable='On';
                app.XshiftSpinner.Value=app.XshiftSpinner.Value + 0.2;
                if app.ShareAcrossGroupCheckBox.Value==1
                    app.ShareAcrossGroupCheckBox.Enable='Off';
                end
                app.BootstrapCheckBox.Enable='Off';
            end
            app.DataTable.Data=[];
            app.DataTable.ColumnName=[];
            app.GroupNameEditField.Value='';
            app.CompiledSetDropDown.Value='';
            app.DatatoplotDropDown.Value='';  
            
        end

        % Button pushed function: AddGroupButton
        function AddGroupButtonPushed(app, event)
            app.UIFigure.UserData.PlotInfo.yRange=[app.YMinEditField.Value,app.YMaxEditField.Value];
            app.UIFigure.UserData.PlotInfo.GroupName=app.GroupNameEditField.Value;
            app.CompiledSetDropDown.Enable='Off';
            app.DatatoplotDropDown.Enable='Off';
            app.AddAllButton.Enable='Off';
            app.StartNewGroupButton.Enable='On';
            app.GeneratePlotButton.Enable='On';
            if app.BootstrapCheckBox.Value == 0
                app.UIFigure.UserData.PlotInfo.Bootstrap = [];
            else
                app.UIFigure.UserData.PlotInfo.Bootstrap = app.EnterpermutationsEditField.Value;
            end
            app.UIFigure.UserData.PlotInfo.MarkerSize = app.MarkerSizeSpinner.Value;
            app.UIFigure.UserData.PlotInfo.MarkerType = app.MarkerTypeDropDown.Value;
            app.UIFigure.UserData.PlotInfo.MarkerFill = app.FillMarkerCheckBox.Value;
            app.UIFigure.UserData.PlotInfo.colors = [app.MarkerColorRSpinner.Value,app.MarkerColorGSpinner.Value,app.MarkerColorBSpinner.Value];
            %app.UIFigure.UserData.PlotInfo.transparency = app.BootstrapTransparencySpinner.Value;
            app.UIFigure.UserData.PlotInfo.totalAreas = app.PlotAreasSwitch.Value;
            app.UIFigure.UserData.PlotInfo.maxArea = app.DataWidthSpinner.Value;
            app.UIFigure.UserData.PlotInfo.xShift = app.XshiftSpinner.Value;
            app.UIFigure.UserData.PlotInfo.meanMarker = app.MeanTypeDropDown.Value;
            app.UIFigure.UserData.PlotInfo.meanMarkerColor = [app.MeanMarkerColorRSpinner.Value,app.MeanMarkerColorGSpinner.Value,app.MeanMarkerColorBSpinner.Value];
            app.UIFigure.UserData.PlotInfo.meanMarkerSize = app.MeanMarkerSizeSpinner.Value;
            app.UIFigure.UserData.PlotInfo.meanMarkerFill = app.FillMeanMarkerCheckBox.Value;
            app.UIFigure.UserData.PlotInfo.BootstrapTransparency=app.BootstrapTransparencySpinner.Value;
            app.UIFigure.UserData.PlotInfo.BootstrapMaxArea = app.BootstrapWidthSpinner.Value;
            app.UIFigure.UserData.PlotInfo.BootstrapColors = [app.TearDropColorRSpinner.Value,app.TearDropColorGSpinner.Value,app.TearDropColorBSpinner.Value];
            app.UIFigure.UserData.PlotInfo.LabelShare = app.ShareAcrossGroupCheckBox.Value;
            app.UIFigure.UserData.PlotInfo.LabelStagger = app.LevelstoStaggerSpinner.Value;
            app.UIFigure.UserData.PlotInfo.LabelRotate = app.RotationDegreeSpinner.Value;            
            app.UIFigure.UserData.PlotInfo.LabelFontSize = app.FontsizeSpinner.Value;
            app.UIFigure.UserData.PlotInfo.LabelFont = app.FontDropDown.Value;
            app.UIFigure.UserData.PlotInfo.LabelBold = app.BoldCheckBox.Value;
            app.UIFigure.UserData.PlotInfo.LabelItalics = app.ItalicsCheckBox.Value;  
            app.UIFigure.UserData.PlotInfo.LabelInterpreter = app.InterpreterDropDown.Value; 
            app.UIFigure.UserData.PlotInfo.IncludeLegend = app.IncludeLegendCheckBox.Value; 
            app.UIFigure.UserData.PlotInfo.LegendLocation = app.LegendPositionDropDown.Value; 
            app.UIFigure.UserData.PlotInfo.AddLine = app.AddXlineCheckBox.Value; 
            app.UIFigure.UserData.PlotInfo.XLinePosition = app.XLinePositionSpinner.Value;
            if app.UIFigure.UserData.GroupCount==1
                app.UITable2Graph.Data=struct2table(app.UIFigure.UserData.PlotInfo,'AsArray',1);
                app.UITable2Graph.ColumnName=app.UITable2Graph.Data.Properties.VariableNames;
            else
                app.UITable2Graph.Data=[app.UITable2Graph.Data;struct2table(app.UIFigure.UserData.PlotInfo,'AsArray',1)];
            end   

        end

        % Button pushed function: AddDataButton
        function AddDataButtonPushed(app, event)
            UseTableRow=app.UIFigure.UserData.CompiledDataTable(app.UIFigure.UserData.UseRowInd==1,:);
            if strcmpi(app.DatatoplotDropDown.Value,'Blank')
                app.UIFigure.UserData.PlotInfo.Data{1,app.UIFigure.UserData.MemberCount}=[];
                app.UIFigure.UserData.PlotInfo.XLabel{1,app.UIFigure.UserData.MemberCount}='';
            else
                try
                    app.UIFigure.UserData.PlotInfo.Data{1,app.UIFigure.UserData.MemberCount}=UseTableRow.Data{1,1}.(app.DatatoplotDropDown.Value);            
                catch
                    app.UIFigure.UserData.PlotInfo.Data{1,app.UIFigure.UserData.MemberCount}=UseTableRow.Data{1,1}(:,ismember(UseTableRow.DataLabels(1,:),app.DatatoplotDropDown.Value));
                end
                app.UIFigure.UserData.PlotInfo.XLabel{1,app.UIFigure.UserData.MemberCount}=app.XDataLabelEditField.Value;
            end
            app.DataTable.Data=cell2table(app.UIFigure.UserData.PlotInfo.Data);
            app.DataTable.ColumnName=app.UIFigure.UserData.PlotInfo.XLabel;
            app.UIFigure.UserData.MemberCount=app.UIFigure.UserData.MemberCount+1;
            app.DatatoplotDropDown.Value='';
            app.XDataLabelEditField.Value='';
            app.AddGroupButton.Enable='On';
            if strcmpi(app.GroupNameEditField.Value,'')
                app.GroupNameEditField.Value=app.CompiledSetDropDown.Value;
            end
        end

        % Value changed function: ColorDropDown
        function ColorDropDownValueChanged(app, event)
            value = app.ColorDropDown.Value;
            if strcmpi(value,'red')
                app.MarkerColorRSpinner.Value=1; app.MarkerColorGSpinner.Value=0; app.MarkerColorBSpinner.Value=0;
            end
            if strcmpi(value,'green')
                app.MarkerColorRSpinner.Value=0; app.MarkerColorGSpinner.Value=1; app.MarkerColorBSpinner.Value=0;
            end
            if strcmpi(value,'blue')
                app.MarkerColorRSpinner.Value=0; app.MarkerColorGSpinner.Value=0; app.MarkerColorBSpinner.Value=1;
            end
            if strcmpi(value,'cyan')
                app.MarkerColorRSpinner.Value=0; app.MarkerColorGSpinner.Value=1; app.MarkerColorBSpinner.Value=1;
            end
            if strcmpi(value,'magenta')
                app.MarkerColorRSpinner.Value=1; app.MarkerColorGSpinner.Value=0; app.MarkerColorBSpinner.Value=1;
            end
            if strcmpi(value,'yellow')
                app.MarkerColorRSpinner.Value=1; app.MarkerColorGSpinner.Value=1; app.MarkerColorBSpinner.Value=0;
            end 
            if strcmpi(value,'black')
                app.MarkerColorRSpinner.Value=0; app.MarkerColorGSpinner.Value=0; app.MarkerColorBSpinner.Value=0;
            end       
            if strcmpi(value,'gray')
                app.MarkerColorGraySpinner.Enable='On';
                gr=app.MarkerColorGraySpinner.Value;
                app.MarkerColorRSpinner.Value=gr; app.MarkerColorGSpinner.Value=gr; app.MarkerColorBSpinner.Value=gr;
            else
                app.MarkerColorGraySpinner.Enable='Off';
            end
        end

        % Value changed function: MeanColorDropDown
        function MeanColorDropDownValueChanged(app, event)
            value = app.MeanColorDropDown.Value;
            if strcmpi(value,'red')
                app.MeanMarkerColorRSpinner.Value=1; app.MeanMarkerColorGSpinner.Value=0; app.MeanMarkerColorBSpinner.Value=0;
            end
            if strcmpi(value,'green')
                app.MeanMarkerColorRSpinner.Value=0; app.MeanMarkerColorGSpinner.Value=1; app.MeanMarkerColorBSpinner.Value=0;
            end
            if strcmpi(value,'blue')
                app.MeanMarkerColorRSpinner.Value=0; app.MeanMarkerColorGSpinner.Value=0; app.MeanMarkerColorBSpinner.Value=1;
            end
            if strcmpi(value,'cyan')
                app.MeanMarkerColorRSpinner.Value=0; app.MeanMarkerColorGSpinner.Value=1; app.MeanMarkerColorBSpinner.Value=1;
            end
            if strcmpi(value,'magenta')
                app.MeanMarkerColorRSpinner.Value=1; app.MeanMarkerColorGSpinner.Value=0; app.MeanMarkerColorBSpinner.Value=1;
            end
            if strcmpi(value,'yellow')
                app.MeanMarkerColorRSpinner.Value=1; app.MeanMarkerColorGSpinner.Value=1; app.MeanMarkerColorBSpinner.Value=0;
            end 
            if strcmpi(value,'black')
                app.MeanMarkerColorRSpinner.Value=0; app.MeanMarkerColorGSpinner.Value=0; app.MeanMarkerColorBSpinner.Value=0;
            end       
            if strcmpi(value,'gray')
                app.MeanMarkerColorGraySpinner.Enable='On';
                gr=app.MeanMarkerColorGraySpinner.Value;
                app.MeanMarkerColorRSpinner.Value=gr; app.MeanMarkerColorGSpinner.Value=gr; app.MeanMarkerColorBSpinner.Value=gr;
            else
                app.MeanMarkerColorGraySpinner.Enable='Off';
            end               
        end

        % Value changed function: BootstrapCheckBox
        function BootstrapCheckBoxValueChanged(app, event)
            value = app.BootstrapCheckBox.Value;
            if value == 1
                app.EnterpermutationsEditField.Enable='On';
                app.BootstrapWidthSpinner.Enable='On';
                app.BootstrapTransparencySpinner.Enable='On';
                app.TearDropColorDropDown.Enable='On';
                app.TearDropColorRSpinner.Enable='On';
                app.TearDropColorGSpinner.Enable='On';
                app.TearDropColorBSpinner.Enable='On';
            else
                app.EnterpermutationsEditField.Enable='Off';
                app.BootstrapWidthSpinner.Enable='Off';
                app.BootstrapTransparencySpinner.Enable='Off';
                app.TearDropColorDropDown.Enable='Off';
                app.TearDropColorRSpinner.Enable='Off';
                app.TearDropColorGSpinner.Enable='Off';
                app.TearDropColorBSpinner.Enable='Off';
            end
        end

        % Value changed function: MarkerColorGraySpinner
        function MarkerColorGraySpinnerValueChanged(app, event)
            value = app.MarkerColorGraySpinner.Value;
            app.MarkerColorRSpinner.Value=value; app.MarkerColorGSpinner.Value=value; app.MarkerColorBSpinner.Value=value;
        end

        % Value changed function: MeanMarkerColorGraySpinner
        function MeanMarkerColorGraySpinnerValueChanged(app, event)
            value = app.MeanMarkerColorGraySpinner.Value;
            app.MeanMarkerColorRSpinner.Value=value; app.MeanMarkerColorGSpinner.Value=value; app.MeanMarkerColorBSpinner.Value=value;
        end

        % Button pushed function: GeneratePlotButton
        function GeneratePlotButtonPushed(app, event)
            PlotInfo=app.UITable2Graph.Data;
            %app.UIAxes.Visible='On';
            UseAxes=DataPointDropPlot_4App(PlotInfo);
            app.ResetPlotButton.Enable='On';
            app.GeneratePlotButton.Enable='Off';
            %app.UIAxes=UseAxes;
        end

        % Button pushed function: ResetPlotButton
        function ResetPlotButtonPushed(app, event)
            close
            app.ResetPlotButton.Enable='Off';
            app.GeneratePlotButton.Enable='On';
        end

        % Value changed function: TearDropColorDropDown
        function TearDropColorDropDownValueChanged(app, event)
            value = app.TearDropColorDropDown.Value;
            if strcmpi(value,'red')
                app.TearDropColorRSpinner.Value=1; app.TearDropColorGSpinner.Value=0.6; app.TearDropColorBSpinner.Value=0.6;
            end
            if strcmpi(value,'green')
                app.TearDropColorRSpinner.Value=0.6; app.TearDropColorGSpinner.Value=1; app.TearDropColorBSpinner.Value=0.6;
            end
            if strcmpi(value,'blue')
                app.TearDropColorRSpinner.Value=0.6; app.TearDropColorGSpinner.Value=0.6; app.TearDropColorBSpinner.Value=1;
            end
            if strcmpi(value,'cyan')
                app.TearDropColorRSpinner.Value=0.3; app.TearDropColorGSpinner.Value=1; app.TearDropColorBSpinner.Value=1;
            end
            if strcmpi(value,'magenta')
                app.TearDropColorRSpinner.Value=1; app.TearDropColorGSpinner.Value=0.3; app.TearDropColorBSpinner.Value=1;
            end
            if strcmpi(value,'yellow')
                app.TearDropColorRSpinner.Value=1; app.TearDropColorGSpinner.Value=1; app.TearDropColorBSpinner.Value=0.3;
            end 
            if strcmpi(value,'black')
                app.TearDropColorRSpinner.Value=0; app.TearDropColorGSpinner.Value=0; app.TearDropColorBSpinner.Value=0;
            end       
            if strcmpi(value,'gray')
                app.TearDropColorGraySpinner.Enable='On';
                gr=app.TearDropColorGraySpinner.Value;
                app.TearDropColorRSpinner.Value=gr; app.TearDropColorGSpinner.Value=gr; app.TearDropColorBSpinner.Value=gr;
            else
                app.TearDropColorGraySpinner.Enable='Off';
            end                  
        end

        % Value changed function: XLabelpositionDropDown
        function XLabelpositionDropDownValueChanged(app, event)
            value = app.XLabelpositionDropDown.Value;
            if strcmpi(value,'Straight')
                app.LevelstoStaggerSpinner.Enable='Off';
                app.RotationDegreeSpinner.Enable='Off';
                app.LevelstoStaggerSpinner.Value=1;
                app.RotationDegreeSpinner.Value=0;
            elseif strcmpi(value,'Staggered')
                app.LevelstoStaggerSpinner.Enable='On';
                app.LevelstoStaggerSpinner.Value=2;
                app.RotationDegreeSpinner.Enable='Off';
                app.RotationDegreeSpinner.Value=0;
            else
                app.LevelstoStaggerSpinner.Enable='Off';
                app.RotationDegreeSpinner.Enable='On';  
                app.LevelstoStaggerSpinner.Value=1;
                app.RotationDegreeSpinner.Value=60;
                
            end
        end

        % Value changed function: YMinEditField
        function YMinEditFieldValueChanged(app, event)
            value = app.YMinEditField.Value;
            app.XLinePositionSpinner.Limits(1,1)=value;
        end

        % Value changed function: YMaxEditField
        function YMaxEditFieldValueChanged(app, event)
            value = app.YMaxEditField.Value;
            app.XLinePositionSpinner.Limits(1,2)=value;           
        end

        % Button pushed function: ResetDataTableButton
        function ResetDataTableButtonPushed(app, event)
            app.DataTable.Data=[];
            app.DataTable.ColumnName=[];
            app.UIFigure.UserData.MemberCount=1;
            app.DatatoplotDropDown.Value='';
            app.XDataLabelEditField.Value='';
            if strcmpi(app.GroupNameEditField.Value,'')
                app.GroupNameEditField.Value=app.CompiledSetDropDown.Value;
            end     
            app.DatatoplotDropDown.Enable='On';
        end

        % Button pushed function: ResetPlotTableButton
        function ResetPlotTableButtonPushed(app, event)
            app.UIFigure.UserData.PlotInfo=struct;
            app.StartNewGroupButton.Enable='Off';
            app.AddGroupButton.Enable='Off';
            app.UIFigure.UserData.MemberCount=1; %(Re)Start member count from 1
            app.UIFigure.UserData.GroupCount=1;%Count to next group
            app.AddDataButton.Enable='On';
            app.AddAllButton.Enable='On';
            app.AddXlineCheckBox.Enable='On';
            app.BootstrapCheckBox.Enable='On';
            app.ShareAcrossGroupCheckBox.Enable='On';
            app.CompiledSetDropDown.Enable='On';
            app.DatatoplotDropDown.Enable='On';
            if app.UIFigure.UserData.GroupCount > 1
                app.XshiftSpinner.Enable='On';
                app.XshiftSpinner.Value=app.XshiftSpinner.Value + 0.2;
                if app.ShareAcrossGroupCheckBox.Value==1
                    app.ShareAcrossGroupCheckBox.Enable='Off';
                end
                app.BootstrapCheckBox.Enable='Off';
            end
            app.DataTable.Data=[];
            app.DataTable.ColumnName=[];
            app.GroupNameEditField.Value='';
            app.CompiledSetDropDown.Value='';
            app.DatatoplotDropDown.Value='';     
            app.UITable2Graph.Data=[];
            app.UITable2Graph.ColumnName=[];
        end

        % Button pushed function: SaveCurrentPlotButton
        function SaveCurrentPlotButtonPushed(app, event)
            saveDir=strrep(app.UITable.Data.ExperimentsDir{1,1},'\','/');
            saveDir=strrep(saveDir,'/Experiments/','/');
            saveDir=[saveDir,'App_Figures/'];
            if ~exist(saveDir,'file')
                mkdir(saveDir);
            end
            groupNames=app.UITable2Graph.Data.GroupName;
            fileName=[];
            for i = 1:size(groupNames,1)
                fileName=[fileName,groupNames{i,1},'_'];
            end
            fileName=[fileName,genDateString];
            UniqueName=0;
            count=1;
            NameAppend='';
            while UniqueName==0
                if ~exist([saveDir,fileName,NameAppend,'.png'],'file')
                    saveName=[saveDir,fileName,NameAppend,'.png'];
                    UniqueName=1;                    
                else
                    NameAppend=['_n',num2str(count)];
                    count=count+1;
                end
            end
            export_fig(saveName,'-Transparent','-png','-m6')
        end

        % Button pushed function: AddAllButton
        function AddAllButtonPushed(app, event)
            AllLabels=app.DatatoplotDropDown.Items(3:end);
            UseTableRow=app.UIFigure.UserData.CompiledDataTable(app.UIFigure.UserData.UseRowInd==1,:);
            for i = 1:length(AllLabels)
                app.DatatoplotDropDown.Value=AllLabels{i};
                app.XDataLabelEditField.Value=AllLabels{i};
                try
                    app.UIFigure.UserData.PlotInfo.Data{1,app.UIFigure.UserData.MemberCount}=UseTableRow.Data{1,1}.(app.DatatoplotDropDown.Value);            
                catch
                    app.UIFigure.UserData.PlotInfo.Data{1,app.UIFigure.UserData.MemberCount}=UseTableRow.Data{1,1}(:,ismember(UseTableRow.DataLabels(1,:),app.DatatoplotDropDown.Value));
                end
                app.UIFigure.UserData.PlotInfo.XLabel{1,app.UIFigure.UserData.MemberCount}=app.XDataLabelEditField.Value;
                app.DataTable.Data=cell2table(app.UIFigure.UserData.PlotInfo.Data);
                app.DataTable.ColumnName=app.UIFigure.UserData.PlotInfo.XLabel;
                app.UIFigure.UserData.MemberCount=app.UIFigure.UserData.MemberCount+1;
                app.DatatoplotDropDown.Value='';
                app.XDataLabelEditField.Value='';
                app.AddGroupButton.Enable='On';
                if strcmpi(app.GroupNameEditField.Value,'')
                    app.GroupNameEditField.Value=app.CompiledSetDropDown.Value;
                end  
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CompileDataTab = uitab(app.TabGroup);
            app.CompileDataTab.Title = 'CompileData';
            app.DatasetstocompilePanel = uipanel(app.CompileDataTab);
            app.DatasetstocompilePanel.Title = 'Datasets to compile';
            app.UITable = uitable(app.DatasetstocompilePanel);
            app.UITable.ColumnName = {''};
            app.UITable.RowName = {};
            app.AddMoreButton = uibutton(app.DatasetstocompilePanel, 'push');
            app.AddMoreButton.ButtonPushedFcn = createCallbackFcn(app, @AddMoreButtonPushed, true);
            app.AddMoreButton.Enable = 'off';
            app.AddMoreButton.Text = 'Add to Table';
            app.CompileDataButton = uibutton(app.DatasetstocompilePanel, 'push');
            app.CompileDataButton.ButtonPushedFcn = createCallbackFcn(app, @CompileDataButtonPushed, true);
            app.CompileDataButton.Text = 'Compile Data';
            app.StartNewButton = uibutton(app.DatasetstocompilePanel, 'push');
            app.StartNewButton.ButtonPushedFcn = createCallbackFcn(app, @StartNewButtonPushed, true);
            app.StartNewButton.Text = 'Start New Set';
            app.EnterdatalabelEditFieldLabel = uilabel(app.DatasetstocompilePanel);
            app.EnterdatalabelEditFieldLabel.Text = 'Enter data label';
            app.EnterDataLabelEditField = uieditfield(app.DatasetstocompilePanel, 'text');
            app.EnterDataLabelEditField.Enable = 'off';
            app.SelectdirectoryandparticipanttablePanel = uipanel(app.CompileDataTab);
            app.SelectdirectoryandparticipanttablePanel.Title = '(1) Select directory and participant table';
            app.GetExperimentDirectoryButton = uibutton(app.SelectdirectoryandparticipanttablePanel, 'push');
            app.GetExperimentDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @GetExperimentDirectoryButtonPushed, true);
            app.GetExperimentDirectoryButton.Text = 'Get Experiment Directory';
            app.SelectExperimentTableButton = uibutton(app.SelectdirectoryandparticipanttablePanel, 'push');
            app.SelectExperimentTableButton.ButtonPushedFcn = createCallbackFcn(app, @SelectExperimentTableButtonPushed, true);
            app.SelectExperimentTableButton.Text = 'Select Experiment Table';
            app.ExpDirEditField = uieditfield(app.SelectdirectoryandparticipanttablePanel, 'text');
            app.ExpDirEditField.Editable = 'off';
            app.ExpTableName = uieditfield(app.SelectdirectoryandparticipanttablePanel, 'text');
            app.ExpTableName.Editable = 'off';
            app.ResetButton = uibutton(app.SelectdirectoryandparticipanttablePanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.SubjectorRunPanel = uipanel(app.CompileDataTab);
            app.SubjectorRunPanel.Title = '(2) Subject or Run';
            app.Switch = uiswitch(app.SubjectorRunPanel, 'toggle');
            app.Switch.Items = {'Run', 'Subject'};
            app.Switch.Value = 'Run';
            app.SetButton = uibutton(app.SubjectorRunPanel, 'push');
            app.SetButton.ButtonPushedFcn = createCallbackFcn(app, @SetButtonPushed, true);
            app.SetButton.Enable = 'off';
            app.SetButton.Text = 'Set';
            app.DefineDatasetsPanel = uipanel(app.CompileDataTab);
            app.DefineDatasetsPanel.Title = '(3) Define Datasets';
            app.TypeDropDownLabel = uilabel(app.DefineDatasetsPanel);
            app.TypeDropDownLabel.Enable = 'off';
            app.TypeDropDownLabel.Text = 'Type';
            app.TypeDropDown = uidropdown(app.DefineDatasetsPanel);
            app.TypeDropDown.Items = {'Undefined'};
            app.TypeDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeDropDownValueChanged, true);
            app.TypeDropDown.Enable = 'off';
            app.TypeDropDown.Value = 'Undefined';
            app.NameDropDownLabel = uilabel(app.DefineDatasetsPanel);
            app.NameDropDownLabel.Enable = 'off';
            app.NameDropDownLabel.Text = 'Name';
            app.NameDropDown = uidropdown(app.DefineDatasetsPanel);
            app.NameDropDown.Items = {'Undefined'};
            app.NameDropDown.ValueChangedFcn = createCallbackFcn(app, @NameDropDownValueChanged, true);
            app.NameDropDown.Enable = 'off';
            app.NameDropDown.Value = 'Undefined';
            app.ParcellationDropDownLabel = uilabel(app.DefineDatasetsPanel);
            app.ParcellationDropDownLabel.Enable = 'off';
            app.ParcellationDropDownLabel.Text = 'Parcellation';
            app.ParcellationDropDown = uidropdown(app.DefineDatasetsPanel);
            app.ParcellationDropDown.Items = {'Undefined'};
            app.ParcellationDropDown.ValueChangedFcn = createCallbackFcn(app, @ParcellationDropDownValueChanged, true);
            app.ParcellationDropDown.Enable = 'off';
            app.ParcellationDropDown.Value = 'Undefined';
            app.VariableDropDownLabel = uilabel(app.DefineDatasetsPanel);
            app.VariableDropDownLabel.Enable = 'off';
            app.VariableDropDownLabel.Text = 'Variable';
            app.VariableDropDown = uidropdown(app.DefineDatasetsPanel);
            app.VariableDropDown.Items = {'Undefined'};
            app.VariableDropDown.ValueChangedFcn = createCallbackFcn(app, @VariableDropDownValueChanged, true);
            app.VariableDropDown.Enable = 'off';
            app.VariableDropDown.Value = 'Undefined';
            app.SetDataParametersPanel = uipanel(app.CompileDataTab);
            app.SetDataParametersPanel.Title = '(4) Set Data Parameters';
            app.DataTypeDropDownLabel = uilabel(app.SetDataParametersPanel);
            app.DataTypeDropDownLabel.Enable = 'off';
            app.DataTypeDropDownLabel.Text = 'DataType';
            app.DataTypeDropDown = uidropdown(app.SetDataParametersPanel);
            app.DataTypeDropDown.Items = {'ByParcellation', 'ByCoordinate', 'Other'};
            app.DataTypeDropDown.Enable = 'off';
            app.DataTypeDropDown.Value = 'ByParcellation';
            app.LoadFormatDropDownLabel = uilabel(app.SetDataParametersPanel);
            app.LoadFormatDropDownLabel.Enable = 'off';
            app.LoadFormatDropDownLabel.Text = 'Load Format';
            app.LoadFormatDropDown = uidropdown(app.SetDataParametersPanel);
            app.LoadFormatDropDown.Items = {'Table', 'Array', 'Cell'};
            app.LoadFormatDropDown.Enable = 'off';
            app.LoadFormatDropDown.Value = 'Table';
            app.SaveTypeSwitchLabel = uilabel(app.SetDataParametersPanel);
            app.SaveTypeSwitchLabel.Enable = 'off';
            app.SaveTypeSwitchLabel.Text = 'Save Type';
            app.SaveTypeSwitch = uiswitch(app.SetDataParametersPanel, 'slider');
            app.SaveTypeSwitch.Items = {'Vertical', 'Matrix'};
            app.SaveTypeSwitch.Enable = 'off';
            app.SaveTypeSwitch.Value = 'Vertical';
            app.SaveFormatSwitchLabel = uilabel(app.SetDataParametersPanel);
            app.SaveFormatSwitchLabel.Enable = 'off';
            app.SaveFormatSwitchLabel.Text = 'Save Format';
            app.SaveFormatSwitch = uiswitch(app.SetDataParametersPanel, 'slider');
            app.SaveFormatSwitch.Items = {'Table', 'Array'};
            app.SaveFormatSwitch.Enable = 'off';
            app.SaveFormatSwitch.Value = 'Table';
            app.TableRow = uispinner(app.CompileDataTab);
            app.TableRow.Editable = 'off';
            app.TableRow.Enable = 'off';
            app.TableRow.Visible = 'off';
            app.TableRow.Value = 1;
            app.GraphDataTab = uitab(app.TabGroup);
            app.GraphDataTab.Title = 'Graph Data';
            app.SelectAndLabelDataPanel = uipanel(app.GraphDataTab);
            app.SelectAndLabelDataPanel.Title = 'Select And Label Data';
            app.CompiledSetDropDownLabel = uilabel(app.SelectAndLabelDataPanel);
            app.CompiledSetDropDownLabel.Enable = 'off';
            app.CompiledSetDropDownLabel.Text = 'Compiled Set';
            app.CompiledSetDropDown = uidropdown(app.SelectAndLabelDataPanel);
            app.CompiledSetDropDown.Items = {'Undefined', ''};
            app.CompiledSetDropDown.ValueChangedFcn = createCallbackFcn(app, @CompiledSetDropDownValueChanged, true);
            app.CompiledSetDropDown.Enable = 'off';
            app.CompiledSetDropDown.Value = '';
            app.DatatoplotDropDownLabel = uilabel(app.SelectAndLabelDataPanel);
            app.DatatoplotDropDownLabel.Enable = 'off';
            app.DatatoplotDropDownLabel.Text = 'Data to plot';
            app.DatatoplotDropDown = uidropdown(app.SelectAndLabelDataPanel);
            app.DatatoplotDropDown.Items = {'Undefined', ''};
            app.DatatoplotDropDown.ValueChangedFcn = createCallbackFcn(app, @DatatoplotDropDownValueChanged, true);
            app.DatatoplotDropDown.Enable = 'off';
            app.DatatoplotDropDown.Value = '';
            app.AddDataButton = uibutton(app.SelectAndLabelDataPanel, 'push');
            app.AddDataButton.ButtonPushedFcn = createCallbackFcn(app, @AddDataButtonPushed, true);
            app.AddDataButton.Enable = 'off';
            app.AddDataButton.Text = ' Add Data';
            app.GroupNameEditFieldLabel = uilabel(app.SelectAndLabelDataPanel);
            app.GroupNameEditFieldLabel.Text = 'Group Name';
            app.GroupNameEditField = uieditfield(app.SelectAndLabelDataPanel, 'text');
            app.XDataLabelEditFieldLabel = uilabel(app.SelectAndLabelDataPanel);
            app.XDataLabelEditFieldLabel.Text = 'X Data Label';
            app.XDataLabelEditField = uieditfield(app.SelectAndLabelDataPanel, 'text');
            app.StartNewGroupButton = uibutton(app.SelectAndLabelDataPanel, 'push');
            app.StartNewGroupButton.ButtonPushedFcn = createCallbackFcn(app, @StartNewGroupButtonPushed, true);
            app.StartNewGroupButton.Text = 'Start New Group';
            app.AddGroupButton = uibutton(app.SelectAndLabelDataPanel, 'push');
            app.AddGroupButton.ButtonPushedFcn = createCallbackFcn(app, @AddGroupButtonPushed, true);
            app.AddGroupButton.Enable = 'off';
            app.AddGroupButton.Text = 'Add Group';
            app.AddAllButton = uibutton(app.SelectAndLabelDataPanel, 'push');
            app.AddAllButton.ButtonPushedFcn = createCallbackFcn(app, @AddAllButtonPushed, true);
            app.AddAllButton.Enable = 'off';
            app.AddAllButton.Text = ' Add All Data';
            app.PlotTablePanel = uipanel(app.GraphDataTab);
            app.PlotTablePanel.Title = 'Plot Table';
            app.UITable2Graph = uitable(app.PlotTablePanel);
            app.UITable2Graph.ColumnName = {''};
            app.UITable2Graph.RowName = {};
            app.UITable2Graph.ColumnEditable = true;
            app.TabGroup2 = uitabgroup(app.GraphDataTab);
            app.GeneralTab = uitab(app.TabGroup2);
            app.GeneralTab.Title = 'General';
            app.YRangeEditFieldLabel = uilabel(app.GeneralTab);
            app.YRangeEditFieldLabel.Text = 'Y Range';
            app.YMinEditField = uieditfield(app.GeneralTab, 'numeric');
            app.YMinEditField.ValueChangedFcn = createCallbackFcn(app, @YMinEditFieldValueChanged, true);
            app.YMinEditField.Value = -1;
            app.FromLabel = uilabel(app.GeneralTab);
            app.FromLabel.Text = 'From';
            app.YMaxEditField = uieditfield(app.GeneralTab, 'numeric');
            app.YMaxEditField.ValueChangedFcn = createCallbackFcn(app, @YMaxEditFieldValueChanged, true);
            app.YMaxEditField.Value = 1;
            app.ToLabel = uilabel(app.GeneralTab);
            app.ToLabel.Text = 'To';
            app.EnterpermutationsEditFieldLabel = uilabel(app.GeneralTab);
            app.EnterpermutationsEditFieldLabel.Enable = 'off';
            app.EnterpermutationsEditFieldLabel.Text = 'Enter # permutations';
            app.EnterpermutationsEditField = uieditfield(app.GeneralTab, 'numeric');
            app.EnterpermutationsEditField.Limits = [0 100000];
            app.EnterpermutationsEditField.RoundFractionalValues = 'on';
            app.EnterpermutationsEditField.Enable = 'off';
            app.EnterpermutationsEditField.Value = 1000;
            app.XshiftSpinnerLabel = uilabel(app.GeneralTab);
            app.XshiftSpinnerLabel.Enable = 'off';
            app.XshiftSpinnerLabel.Text = 'X shift';
            app.XshiftSpinner = uispinner(app.GeneralTab);
            app.XshiftSpinner.Step = 0.05;
            app.XshiftSpinner.Limits = [0 10];
            app.XshiftSpinner.Enable = 'off';
            app.PlotAreasSwitchLabel = uilabel(app.GeneralTab);
            app.PlotAreasSwitchLabel.Text = 'Plot Areas';
            app.PlotAreasSwitch = uiswitch(app.GeneralTab, 'slider');
            app.PlotAreasSwitch.Items = {'Identical', 'Proportional'};
            app.PlotAreasSwitch.Value = 'Identical';
            app.DataWidthSpinnerLabel = uilabel(app.GeneralTab);
            app.DataWidthSpinnerLabel.Text = 'Data Width';
            app.DataWidthSpinner = uispinner(app.GeneralTab);
            app.DataWidthSpinner.Step = 0.05;
            app.DataWidthSpinner.Limits = [0 1];
            app.DataWidthSpinner.Value = 0.3;
            app.LabelHandeling = uilabel(app.GeneralTab);
            app.LabelHandeling.Text = 'Label Handeling';
            app.ShareAcrossGroupCheckBox = uicheckbox(app.GeneralTab);
            app.ShareAcrossGroupCheckBox.Text = 'Share Across Group?';
            app.XLabelpositionDropDownLabel = uilabel(app.GeneralTab);
            app.XLabelpositionDropDownLabel.Text = 'X Label position';
            app.XLabelpositionDropDown = uidropdown(app.GeneralTab);
            app.XLabelpositionDropDown.Items = {'Straight', 'Staggered', 'Rotated'};
            app.XLabelpositionDropDown.ValueChangedFcn = createCallbackFcn(app, @XLabelpositionDropDownValueChanged, true);
            app.XLabelpositionDropDown.Value = 'Straight';
            app.FontsizeSpinnerLabel = uilabel(app.GeneralTab);
            app.FontsizeSpinnerLabel.Text = 'Font size';
            app.FontsizeSpinner = uispinner(app.GeneralTab);
            app.FontsizeSpinner.Limits = [1 100];
            app.FontsizeSpinner.RoundFractionalValues = 'on';
            app.FontsizeSpinner.Value = 12;
            app.FontDropDownLabel = uilabel(app.GeneralTab);
            app.FontDropDownLabel.Text = 'Font';
            app.FontDropDown = uidropdown(app.GeneralTab);
            app.FontDropDown.Items = {'Courier New'};
            app.FontDropDown.Value = 'Courier New';
            app.InterpreterDropDownLabel = uilabel(app.GeneralTab);
            app.InterpreterDropDownLabel.Text = 'Interpreter';
            app.InterpreterDropDown = uidropdown(app.GeneralTab);
            app.InterpreterDropDown.Items = {'None', 'Tex', 'LaTex'};
            app.InterpreterDropDown.Value = 'None';
            app.BoldCheckBox = uicheckbox(app.GeneralTab);
            app.BoldCheckBox.Text = 'Bold';
            app.ItalicsCheckBox = uicheckbox(app.GeneralTab);
            app.ItalicsCheckBox.Text = 'Italics';
            app.RotationDegreeSpinnerLabel = uilabel(app.GeneralTab);
            app.RotationDegreeSpinnerLabel.Enable = 'off';
            app.RotationDegreeSpinnerLabel.Text = 'Rotation Degree';
            app.RotationDegreeSpinner = uispinner(app.GeneralTab);
            app.RotationDegreeSpinner.Limits = [0 359];
            app.RotationDegreeSpinner.RoundFractionalValues = 'on';
            app.RotationDegreeSpinner.Enable = 'off';
            app.LevelstoStaggerSpinnerLabel = uilabel(app.GeneralTab);
            app.LevelstoStaggerSpinnerLabel.Enable = 'off';
            app.LevelstoStaggerSpinnerLabel.Text = '# Levels to Stagger';
            app.LevelstoStaggerSpinner = uispinner(app.GeneralTab);
            app.LevelstoStaggerSpinner.Limits = [1 10];
            app.LevelstoStaggerSpinner.RoundFractionalValues = 'on';
            app.LevelstoStaggerSpinner.Enable = 'off';
            app.LevelstoStaggerSpinner.Value = 1;
            app.IncludeLegendCheckBox = uicheckbox(app.GeneralTab);
            app.IncludeLegendCheckBox.Text = 'Include Legend?';
            app.BootstrapCheckBox = uicheckbox(app.GeneralTab);
            app.BootstrapCheckBox.ValueChangedFcn = createCallbackFcn(app, @BootstrapCheckBoxValueChanged, true);
            app.BootstrapCheckBox.Text = 'Bootstrap?';
            app.BootstrapDistributionLabel = uilabel(app.GeneralTab);
            app.BootstrapDistributionLabel.Text = 'Bootstrap Distribution';
            app.BootstrapWidthSpinnerLabel = uilabel(app.GeneralTab);
            app.BootstrapWidthSpinnerLabel.Text = 'Bootstrap Width';
            app.BootstrapWidthSpinner = uispinner(app.GeneralTab);
            app.BootstrapWidthSpinner.Step = 0.05;
            app.BootstrapWidthSpinner.Limits = [0 1];
            app.BootstrapWidthSpinner.Value = 0.3;
            app.LegendPositionDropDownLabel = uilabel(app.GeneralTab);
            app.LegendPositionDropDownLabel.Text = 'Legend Position';
            app.LegendPositionDropDown = uidropdown(app.GeneralTab);
            app.LegendPositionDropDown.Items = {'north', 'south', 'east', 'west', 'northeast', 'northwest', 'southeast', 'southwest', 'northoutside', 'southoutside', 'eastoutside', 'westoutside', 'northeastoutside', 'northwestoutside', 'southeastoutside', 'southwestoutside', 'best', 'bestoutside'};
            app.LegendPositionDropDown.Value = 'northeast';
            app.AddXlineCheckBox = uicheckbox(app.GeneralTab);
            app.AddXlineCheckBox.Text = 'Add X line';
            app.XLinePositionSpinnerLabel = uilabel(app.GeneralTab);
            app.XLinePositionSpinnerLabel.Text = 'X Line Position';
            app.XLinePositionSpinner = uispinner(app.GeneralTab);
            app.XLinePositionSpinner.Step = 0.05;
            app.XLinePositionSpinner.Limits = [-1 1];
            app.ColorEtcTab = uitab(app.TabGroup2);
            app.ColorEtcTab.Title = 'ColorEtc';
            app.MeanPointMarkerLabel = uilabel(app.ColorEtcTab);
            app.MeanPointMarkerLabel.Text = 'Mean Point Marker';
            app.EditMeanColorLabel = uilabel(app.ColorEtcTab);
            app.EditMeanColorLabel.Text = 'Edit Color';
            app.SizeSpinnerLabel_2 = uilabel(app.ColorEtcTab);
            app.SizeSpinnerLabel_2.Text = 'Size';
            app.MeanMarkerSizeSpinner = uispinner(app.ColorEtcTab);
            app.MeanMarkerSizeSpinner.Limits = [1 100];
            app.MeanMarkerSizeSpinner.Value = 5;
            app.TypeDropDown_3Label = uilabel(app.ColorEtcTab);
            app.TypeDropDown_3Label.Text = 'Type';
            app.MeanTypeDropDown = uidropdown(app.ColorEtcTab);
            app.MeanTypeDropDown.Items = {'+', '.', 'o', '*', 'x', 'square', 'diamond', '^', 'v', '>', '<', 'pentagram', 'hexagram'};
            app.MeanTypeDropDown.Value = 'o';
            app.ColorDropDown_2Label = uilabel(app.ColorEtcTab);
            app.ColorDropDown_2Label.Text = 'Color';
            app.MeanColorDropDown = uidropdown(app.ColorEtcTab);
            app.MeanColorDropDown.Items = {'red', 'green', 'blue', 'cyan', 'magenta', 'yellow', 'black', 'gray'};
            app.MeanColorDropDown.ValueChangedFcn = createCallbackFcn(app, @MeanColorDropDownValueChanged, true);
            app.MeanColorDropDown.Value = 'black';
            app.RSpinnerLabel_2 = uilabel(app.ColorEtcTab);
            app.RSpinnerLabel_2.Text = 'R';
            app.MeanMarkerColorRSpinner = uispinner(app.ColorEtcTab);
            app.MeanMarkerColorRSpinner.Step = 0.01;
            app.MeanMarkerColorRSpinner.Limits = [0 1];
            app.GSpinnerLabel_2 = uilabel(app.ColorEtcTab);
            app.GSpinnerLabel_2.Text = 'G';
            app.MeanMarkerColorGSpinner = uispinner(app.ColorEtcTab);
            app.MeanMarkerColorGSpinner.Step = 0.01;
            app.MeanMarkerColorGSpinner.Limits = [0 1];
            app.BSpinnerLabel_2 = uilabel(app.ColorEtcTab);
            app.BSpinnerLabel_2.Text = 'B';
            app.MeanMarkerColorBSpinner = uispinner(app.ColorEtcTab);
            app.MeanMarkerColorBSpinner.Step = 0.01;
            app.MeanMarkerColorBSpinner.Limits = [0 1];
            app.DataPointMarkerLabel = uilabel(app.ColorEtcTab);
            app.DataPointMarkerLabel.Text = 'Data Point Marker';
            app.EditColorLabel = uilabel(app.ColorEtcTab);
            app.EditColorLabel.Text = 'Edit Color';
            app.SizeSpinnerLabel_3 = uilabel(app.ColorEtcTab);
            app.SizeSpinnerLabel_3.Text = 'Size';
            app.MarkerSizeSpinner = uispinner(app.ColorEtcTab);
            app.MarkerSizeSpinner.Limits = [1 100];
            app.MarkerSizeSpinner.Value = 5;
            app.TypeDropDown_2Label = uilabel(app.ColorEtcTab);
            app.TypeDropDown_2Label.Text = 'Type';
            app.MarkerTypeDropDown = uidropdown(app.ColorEtcTab);
            app.MarkerTypeDropDown.Items = {'+', '.', 'o', '*', 'x', 'square', 'diamond', '^', 'v', '>', '<', 'pentagram', 'hexagram'};
            app.MarkerTypeDropDown.Value = '+';
            app.ColorDropDownLabel = uilabel(app.ColorEtcTab);
            app.ColorDropDownLabel.Text = 'Color';
            app.ColorDropDown = uidropdown(app.ColorEtcTab);
            app.ColorDropDown.Items = {'undefined', 'red', 'green', 'blue', 'cyan', 'magenta', 'yellow', 'black', 'gray'};
            app.ColorDropDown.ValueChangedFcn = createCallbackFcn(app, @ColorDropDownValueChanged, true);
            app.ColorDropDown.Value = 'undefined';
            app.RSpinnerLabel_3 = uilabel(app.ColorEtcTab);
            app.RSpinnerLabel_3.Text = 'R';
            app.MarkerColorRSpinner = uispinner(app.ColorEtcTab);
            app.MarkerColorRSpinner.Step = 0.01;
            app.MarkerColorRSpinner.Limits = [0 1];
            app.GSpinnerLabel_3 = uilabel(app.ColorEtcTab);
            app.GSpinnerLabel_3.Text = 'G';
            app.MarkerColorGSpinner = uispinner(app.ColorEtcTab);
            app.MarkerColorGSpinner.Step = 0.01;
            app.MarkerColorGSpinner.Limits = [0 1];
            app.BSpinnerLabel_3 = uilabel(app.ColorEtcTab);
            app.BSpinnerLabel_3.Text = 'B';
            app.MarkerColorBSpinner = uispinner(app.ColorEtcTab);
            app.MarkerColorBSpinner.Step = 0.01;
            app.MarkerColorBSpinner.Limits = [0 1];
            app.GrayLabel = uilabel(app.ColorEtcTab);
            app.GrayLabel.Text = 'Gray';
            app.MarkerColorGraySpinner = uispinner(app.ColorEtcTab);
            app.MarkerColorGraySpinner.Step = 0.01;
            app.MarkerColorGraySpinner.Limits = [0 1];
            app.MarkerColorGraySpinner.ValueChangedFcn = createCallbackFcn(app, @MarkerColorGraySpinnerValueChanged, true);
            app.MarkerColorGraySpinner.Enable = 'off';
            app.MarkerColorGraySpinner.Value = 0.5;
            app.FillMeanMarkerCheckBox = uicheckbox(app.ColorEtcTab);
            app.FillMeanMarkerCheckBox.Text = 'Fill Marker?';
            app.GrayLabel_2 = uilabel(app.ColorEtcTab);
            app.GrayLabel_2.Text = 'Gray';
            app.MeanMarkerColorGraySpinner = uispinner(app.ColorEtcTab);
            app.MeanMarkerColorGraySpinner.Step = 0.01;
            app.MeanMarkerColorGraySpinner.Limits = [0 1];
            app.MeanMarkerColorGraySpinner.ValueChangedFcn = createCallbackFcn(app, @MeanMarkerColorGraySpinnerValueChanged, true);
            app.MeanMarkerColorGraySpinner.Enable = 'off';
            app.MeanMarkerColorGraySpinner.Value = 0.5;
            app.FillMarkerCheckBox = uicheckbox(app.ColorEtcTab);
            app.FillMarkerCheckBox.Text = 'Fill Marker?';
            app.TransparencySpinnerLabel = uilabel(app.ColorEtcTab);
            app.TransparencySpinnerLabel.Text = 'Transparency';
            app.BootstrapTransparencySpinner = uispinner(app.ColorEtcTab);
            app.BootstrapTransparencySpinner.Step = 0.001;
            app.BootstrapTransparencySpinner.Limits = [0 1];
            app.BootstrapTransparencySpinner.Value = 0.07;
            app.BootstrapteardropplotLabel = uilabel(app.ColorEtcTab);
            app.BootstrapteardropplotLabel.Text = 'Bootstrap teardrop plot';
            app.ColorDropDown_2Label_2 = uilabel(app.ColorEtcTab);
            app.ColorDropDown_2Label_2.Text = 'Color';
            app.TearDropColorDropDown = uidropdown(app.ColorEtcTab);
            app.TearDropColorDropDown.Items = {'undefined', 'red', 'green', 'blue', 'cyan', 'magenta', 'yellow', 'black', 'gray'};
            app.TearDropColorDropDown.ValueChangedFcn = createCallbackFcn(app, @TearDropColorDropDownValueChanged, true);
            app.TearDropColorDropDown.Value = 'undefined';
            app.GrayLabel_3 = uilabel(app.ColorEtcTab);
            app.GrayLabel_3.Text = 'Gray';
            app.TearDropColorGraySpinner = uispinner(app.ColorEtcTab);
            app.TearDropColorGraySpinner.Step = 0.01;
            app.TearDropColorGraySpinner.Limits = [0 1];
            app.TearDropColorGraySpinner.Enable = 'off';
            app.TearDropColorGraySpinner.Value = 0.5;
            app.RSpinnerLabel_4 = uilabel(app.ColorEtcTab);
            app.RSpinnerLabel_4.Text = 'R';
            app.TearDropColorRSpinner = uispinner(app.ColorEtcTab);
            app.TearDropColorRSpinner.Step = 0.01;
            app.TearDropColorRSpinner.Limits = [0 1];
            app.GSpinnerLabel_4 = uilabel(app.ColorEtcTab);
            app.GSpinnerLabel_4.Text = 'G';
            app.TearDropColorGSpinner = uispinner(app.ColorEtcTab);
            app.TearDropColorGSpinner.Step = 0.01;
            app.TearDropColorGSpinner.Limits = [0 1];
            app.BSpinnerLabel_4 = uilabel(app.ColorEtcTab);
            app.BSpinnerLabel_4.Text = 'B';
            app.TearDropColorBSpinner = uispinner(app.ColorEtcTab);
            app.TearDropColorBSpinner.Step = 0.01;
            app.TearDropColorBSpinner.Limits = [0 1];
            app.StatReportTab = uitab(app.TabGroup2);
            app.StatReportTab.Title = 'Stat Report';
            app.GeneratePlotButton = uibutton(app.GraphDataTab, 'push');
            app.GeneratePlotButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratePlotButtonPushed, true);
            app.GeneratePlotButton.Text = {'Generate'; 'Plot'};
            app.ResetPlotButton = uibutton(app.GraphDataTab, 'push');
            app.ResetPlotButton.ButtonPushedFcn = createCallbackFcn(app, @ResetPlotButtonPushed, true);
            app.ResetPlotButton.Text = {'Reset'; 'Plot'};
            app.DataTablePanel = uipanel(app.GraphDataTab);
            app.DataTablePanel.Title = 'Data Table';
            app.DataTable = uitable(app.DataTablePanel);
            app.DataTable.ColumnName = '';
            app.DataTable.RowName = {};
            app.SaveCurrentPlotButton = uibutton(app.GraphDataTab, 'push');
            app.SaveCurrentPlotButton.ButtonPushedFcn = createCallbackFcn(app, @SaveCurrentPlotButtonPushed, true);
            app.SaveCurrentPlotButton.Text = {'Save'; 'Plot'};
            app.ResetDataTableButton = uibutton(app.GraphDataTab, 'push');
            app.ResetDataTableButton.ButtonPushedFcn = createCallbackFcn(app, @ResetDataTableButtonPushed, true);
            app.ResetDataTableButton.Text = {'Reset'; 'Data Table'};
            app.ResetPlotTableButton = uibutton(app.GraphDataTab, 'push');
            app.ResetPlotTableButton.ButtonPushedFcn = createCallbackFcn(app, @ResetPlotTableButtonPushed, true);
            app.ResetPlotTableButton.Text = {'Reset'; 'Plot Table'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_CompileAndDataPointPlot

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end