classdef app_CompileData < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Title                         matlab.ui.control.Label
        DatasetstocompilePanel        matlab.ui.container.Panel
        UITable                       matlab.ui.control.Table
        AddMoreButton                 matlab.ui.control.Button
        EndButton                     matlab.ui.control.Button
        StartNewButton                matlab.ui.control.Button
        SelectdirectoryandparticipanttablePanel  matlab.ui.container.Panel
        GetExperimentDirectoryButton  matlab.ui.control.Button
        SelectExperimentTableButton   matlab.ui.control.Button
        ExpDirEditField               matlab.ui.control.EditField
        ExpTableName                  matlab.ui.control.EditField
        ResetButton                   matlab.ui.control.Button
        SubjectorRunPanel             matlab.ui.container.Panel
        Switch                        matlab.ui.control.ToggleSwitch
        SetButton                     matlab.ui.control.Button
        DefineDatasetsPanel           matlab.ui.container.Panel
        TypeDropDownLabel             matlab.ui.control.Label
        TypeDropDown                  matlab.ui.control.DropDown
        NameDropDownLabel             matlab.ui.control.Label
        NameDropDown                  matlab.ui.control.DropDown
        ParcellationDropDownLabel     matlab.ui.control.Label
        ParcellationDropDown          matlab.ui.control.DropDown
        VariableDropDownLabel         matlab.ui.control.Label
        VariableDropDown              matlab.ui.control.DropDown
        SetDataParametersPanel        matlab.ui.container.Panel
        DataTypeDropDownLabel         matlab.ui.control.Label
        DataTypeDropDown              matlab.ui.control.DropDown
        LoadFormatDropDownLabel       matlab.ui.control.Label
        LoadFormatDropDown            matlab.ui.control.DropDown
        SaveTypeSwitchLabel           matlab.ui.control.Label
        SaveTypeSwitch                matlab.ui.control.Switch
        SaveFormatSwitch_2Label       matlab.ui.control.Label
        SaveFormatSwitch_2            matlab.ui.control.Switch
        TableRow                      matlab.ui.control.Spinner
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GetExperimentDirectoryButton
        function GetExperimentDirectoryButtonPushed(app, event)
            Ind=app.TableRow.Value;
            ExperimentsDir=uigetdir('/','Select Experiments Directory');
            ExperimentsDir=[ExperimentsDir,'/'];
            app.UIFigure.UserData.ExperimentsDir=ExperimentsDir;
            app.ExpDirEditField.Value=ExperimentsDir;
            app.GetExperimentDirectoryButton.Enable='Off';
        end

        % Button pushed function: SelectExperimentTableButton
        function SelectExperimentTableButtonPushed(app, event)
            Ind=app.TableRow.Value;
            [~,fmriprep_table_names]=getFolderAndFileNames('fmriprep_table/');
            SingleSelect=1;
            fmriprep_table_name=uiNameSelect([{'none- make table'};fmriprep_table_names],'Select fmriprep_table used:',SingleSelect,1);
            fmriprep_table_name=strrep(fmriprep_table_name,'.mat','');
            app.UIFigure.UserData.fmriprep_table_name=fmriprep_table_name;
            app.ExpTableName.Value=fmriprep_table_name;
            tempload=load(['fmriprep_table/',fmriprep_table_name],'fmriprep_table');            
            app.UIFigure.UserData.fmriprep_table=tempload.fmriprep_table;
            app.SelectExperimentTableButton.Enable='Off';
            app.Switch.Enable='On';
            app.SetButton.Enable='on';
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.GetExperimentDirectoryButton.Enable='On';
            app.SelectExperimentTableButton.Enable='On';
        end

        % Button pushed function: SetButton
        function SetButtonPushed(app, event)
            Ind=app.TableRow.Value;
            app.UIFigure.UserData.SubjectOrRun=app.Switch.Value;
            TotalRuns=height(app.UIFigure.UserData.fmriprep_table);
            dataInd=find(app.UIFigure.UserData.fmriprep_table.run==1)';
            if strcmpi(app.UIFigure.UserData.SubjectOrRun,'Subject')
                useIndicies=dataInd;
            else
                useIndicies=[1:TotalRuns];
            end
            AllAnalysisTypes=[];
            for i = useIndicies
                dirName=[app.ExpDirEditField.Value,app.UIFigure.UserData.fmriprep_table.matDir{i,1}];
                [folderNames]=getFolderAndFileNames(dirName);
                AllAnalysisTypes=unique([AllAnalysisTypes;folderNames]);
            end
            app.TypeDropDown.Enable='on';
            app.TypeDropDown.Items=AllAnalysisTypes;  
            app.SetButton.Enable='off';
        end

        % Value changed function: TypeDropDown
        function TypeDropDownValueChanged(app, event)
            Ind=app.TableRow.Value;
            value = app.TypeDropDown.Value;
            if ~strcmpi(value,'Undefined')
                app.UIFigure.UserData.AnalysisType=value; 
                TotalRuns=height(app.UIFigure.UserData.fmriprep_table);
                dataInd=find(app.UIFigure.UserData.fmriprep_table.run==1)';
                if strcmpi(app.UIFigure.UserData.SubjectOrRun,'Subject')
                    useIndicies=dataInd;
                else
                    useIndicies=[1:TotalRuns];
                end
                AllAnalysisNames=[];
                for i = useIndicies
                    dirName=[app.ExpDirEditField.Value,app.UIFigure.UserData.fmriprep_table.matDir{i,1},'/',app.UIFigure.UserData.AnalysisType,'/'];
                    [folderNames]=getFolderAndFileNames(dirName);
                    AllAnalysisNames=unique([AllAnalysisNames;folderNames]);
                end
                app.NameDropDown.Enable='on';
                app.ParcellationDropDown.Enable='on';
                app.VariableDropDown.Enable='on';
                app.NameDropDown.Items=AllAnalysisNames; 
                app.NameDropDown.Value=app.NameDropDown.Items{1,1};
            end
        end

        % Value changed function: NameDropDown
        function NameDropDownValueChanged(app, event)
            Ind=app.TableRow.Value;
            value = app.NameDropDown.Value;
            if ~strcmpi(value,'Undefined')
                app.UIFigure.UserData.AnalysisName=value;
                [app.UIFigure.UserData.filePaths] = BIDsDirSearch(app.ExpDirEditField.Value,app.UIFigure.UserData.fmriprep_table,'SubjectOrRun',app.UIFigure.UserData.SubjectOrRun,'AnalysisType',app.UIFigure.UserData.AnalysisType,'AnalysisName',app.UIFigure.UserData.AnalysisName);           
                app.ParcellationDropDown.Items=app.UIFigure.UserData.filePaths.Properties.VariableNames;  
                app.ParcellationDropDown.Value=app.ParcellationDropDown.Items{1,1};
            end
        end

        % Value changed function: ParcellationDropDown
        function ParcellationDropDownValueChanged(app, event)
            Ind=app.TableRow.Value;
            value = app.ParcellationDropDown.Value;
            if ~strcmpi(value,'Undefined')
                app.UIFigure.UserData.ParcelName=value;
                if length(app.ParcellationDropDown.Items) > 1
                    app.UIFigure.UserData.DataType='ByParcellation';
                    app.DataTypeDropDown.Value='ByParcellation';
                else
                    app.DataTypeDropDown.Enable='On';
                end   
                LoadVariableName=[];
                for i = 1:size(app.UIFigure.UserData.filePaths.(value),1)
                    try
                        variableInfo = who('-file', app.UIFigure.UserData.filePaths.(value){i,1});
                    catch
                        continue
                    end
                    LoadVariableName=unique([LoadVariableName;variableInfo]);
                end
                app.VariableDropDown.Items=LoadVariableName;  
                app.VariableDropDown.Value=app.VariableDropDown.Items{1,1};
            end
        end

        % Value changed function: VariableDropDown
        function VariableDropDownValueChanged(app, event)
            Ind=app.TableRow.Value;
            value = app.VariableDropDown.Value;
            if ~strcmpi(value,'Undefined')
                app.AddMoreButton.Enable='On';
                for i = 1:size(app.UIFigure.UserData.filePaths.(app.UIFigure.UserData.ParcelName),1)
                    try
                        tempLoad=load(app.UIFigure.UserData.filePaths.(app.UIFigure.UserData.ParcelName){i,1},value);
                        tempVar=tempLoad.(value);
                        if istable(tempVar)
                            app.LoadFormatDropDown.Value='Table';
                            app.SaveFormatSwitch_2.Enable='On';
                            if any(ismember(size(tempVar),1))
                                app.UIFigure.UserData.ndim=1;  
                                app.SaveTypeSwitch.Value='Vertical';
                                app.SaveTypeSwitch.Enable='Off';
                            else
                                app.UIFigure.UserData.ndim=2;
                                app.SaveTypeSwitch.Enable='On';
                            end
                        elseif iscell(tempVar)                       
                            app.LoadFormatDropDown.Value='Cell';
                            app.SaveFormatSwitch_2.Value='Array';
                            app.SaveFormatSwitch_2.Enable='Off';
                            if ndims(tempVar)>2
                                app.UIFigure.UserData.ndim=ndims(tempVar);
                                app.SaveTypeSwitch.Value='Matrix';
                                app.SaveTypeSwitch.Enable='Off';
                            elseif ndims(tempVar)==2
                                app.UIFigure.UserData.ndim=2;
                                app.SaveTypeSwitch.Enable='On';
                            else  
                                app.SaveTypeSwitch.Value='Vertical';
                                app.UIFigure.UserData.ndim=1;
                                app.SaveTypeSwitch.Enable='Off';
                            end
                        else
                            app.LoadFormatDropDown.Value='Array';
                            app.SaveFormatSwitch_2.Value='Array';
                            app.SaveFormatSwitch_2.Enable='Off';
                            if ndims(tempVar)>2
                                app.UIFigure.UserData.ndim=ndims(tempVar);
                                app.SaveTypeSwitch.Value='Matrix';
                                app.SaveTypeSwitch.Enable='Off';
                            elseif ndims(tempVar)==2
                                app.UIFigure.UserData.ndim=2;
                                app.SaveTypeSwitch.Enable='On';
                            else  
                                app.UIFigure.UserData.ndim=1;
                                app.SaveTypeSwitch.Value='Vertical';
                                app.SaveTypeSwitch.Enable='Off';
                            end
                        end
                        break
                    catch
                        continue
                    end
                end    
            end
        end

        % Button pushed function: AddMoreButton
        function AddMoreButtonPushed(app, event)
            Ind=app.TableRow.Value;
            TableNames={'ExperimentsDir','fmriprep_table_name','fmriprep_table','SubjectOrRun','AnalysisType','AnalysisName',...
                'ParcelName','LoadVarName','LoadVarFormat','VertOrMat','TableOrArray','DataType','ndim'};
            ExperimentsDir={app.ExpDirEditField.Value};
            fmriprep_table_name={app.ExpTableName.Value};
            fmriprep_table={app.UIFigure.UserData.fmriprep_table};
            SubjectOrRun={app.UIFigure.UserData.SubjectOrRun};
            AnalysisType={app.TypeDropDown.Value};
            AnalysisName={app.NameDropDown.Value};
            ParcelName={app.ParcellationDropDown.Value};
            LoadVarName={app.VariableDropDown.Value};
            LoadVarFormat={app.LoadFormatDropDown.Value};            
            VertOrMat={app.SaveTypeSwitch.Value};
            TableOrArray={app.SaveFormatSwitch_2.Value};
            DataType={app.DataTypeDropDown.Value};
            ndim={app.UIFigure.UserData.ndim};
            app.UITable.ColumnName=TableNames;
            T=cell2table([ExperimentsDir,fmriprep_table_name,fmriprep_table,SubjectOrRun,AnalysisType,AnalysisName,...
                ParcelName,LoadVarName,LoadVarFormat,VertOrMat,TableOrArray,DataType,ndim],'VariableNames',TableNames);
            if Ind == 1
                app.UITable.Data=T;
            else
                app.UITable.Data(Ind,:)=T;
            end
        end

        % Button pushed function: StartNewButton
        function StartNewButtonPushed(app, event)
            app.TableRow.Value=app.TableRow.Value+1;
            app.AddMoreButton.Enable='off';
            app.Switch.Enable='on';
            app.SetButton.Enable='on';
            app.TypeDropDown.Enable='off';
            app.TypeDropDown.Items={'Undefined'};
            app.TypeDropDown.Value='Undefined';
            app.NameDropDown.Enable='off';
            app.NameDropDown.Items={'Undefined'};
            app.NameDropDown.Value='Undefined';            
            app.ParcellationDropDown.Enable='off';
            app.ParcellationDropDown.Items={'Undefined'};
            app.ParcellationDropDown.Value='Undefined';            
            app.VariableDropDown.Enable='off';
            app.VariableDropDown.Items={'Undefined'};
            app.VariableDropDown.Value='Undefined';            
            app.DataTypeDropDown.Enable='off';
            app.SaveTypeSwitch.Enable='off';
            app.SaveFormatSwitch_2.Enable='off';
        end

        % Button pushed function: EndButton
        function EndButtonPushed(app, event)
            UseTable=app.UITable.Data;
            numCompiles=height(UseTable);
            compiledData=cell(numCompiles,2); % {compiledData, compiled_fmriprep_table, selectind, DataLabels}
            for i = 1:numCompiles
                if UseTable.ndim(i,1)==1    
                    [compiledData{i,1},compiledData{i,2},compiledData{i,3},compiledData{i,4}] = ...
                        Compile1D(UseTable.ExperimentsDir{i,1},UseTable.fmriprep_table{i,1},...
                        'AnalysisType',UseTable.AnalysisType{i,1},...
                        'AnalysisName',UseTable.AnalysisName{i,1},...
                        'LoadVarName',UseTable.LoadVarName{i,1},...
                        'LoadVarFormat',UseTable.LoadVarFormat{i,1},...
                        'DataType',UseTable.DataType{i,1},...
                        'SubjectOrRun',UseTable.SubjectOrRun{i,1},...
                        'ParcelName',UseTable.ParcelName{i,1},...
                        'TableOrArray',UseTable.TableOrArray{i,1});
                elseif UseTable.ndim(i,1)==2
                    [compiledData{i,1},compiledData{i,2},compiledData{i,3},compiledData{i,4}] = ...
                        Compile2D(UseTable.ExperimentsDir{i,1},UseTable.fmriprep_table{i,1},...
                        'AnalysisType',UseTable.AnalysisType{i,1},...
                        'AnalysisName',UseTable.AnalysisName{i,1},...
                        'LoadVarName',UseTable.LoadVarName{i,1},...
                        'LoadVarFormat',UseTable.LoadVarFormat{i,1},...
                        'DataType',UseTable.DataType{i,1},...
                        'SubjectOrRun',UseTable.SubjectOrRun{i,1},...
                        'ParcelName',UseTable.ParcelName{i,1},...
                        'TableOrArray',UseTable.TableOrArray{i,1},...
                        'VertOrMat',UseTable.VertOrMat{i,1}); 
                else
                    [compiledData{i,1},compiledData{i,2},compiledData{i,3},compiledData{i,4}] = ...
                        CompileND(UseTable.ExperimentsDir{i,1},UseTable.fmriprep_table{i,1},...
                        'AnalysisType',UseTable.AnalysisType{i,1},...
                        'AnalysisName',UseTable.AnalysisName{i,1},...
                        'LoadVarName',UseTable.LoadVarName{i,1},...
                        'LoadVarFormat',UseTable.LoadVarFormat{i,1},...
                        'DataType',UseTable.DataType{i,1},...
                        'SubjectOrRun',UseTable.SubjectOrRun{i,1},...
                        'ParcelName',UseTable.ParcelName{i,1});     
                end
            end
            CompiledDataTable=[cell2table(compiledData,'VariableNames',{'Data','compiled_fmriprep_table','selectind','DataLabels'}),UseTable];
            app.UIFigure.UserData.CompiledDataTable=CompiledDataTable;
            Temp4Name={'fmriprep_table_name','AnalysisType','AnalysisName','ParcelName','LoadVarName','SubjectOrRun'};
            SaveFolderName=[];
            SaveFileName=[];
            for j = 1:size(Temp4Name,2)
                tName = Temp4Name{1,j};
                if length(unique(CompiledDataTable.(tName))) == numCompiles
                    SaveFolderName=[SaveFolderName,tName,'_'];
                else
                    SaveFileName=[SaveFileName,CompiledDataTable.(tName){1,1},'_'];
                end
            end
            SaveFolderName=strrep([SaveFolderName,'/'],'_/','/');
            SaveFileName=strrep([SaveFileName,'.mat'],'_.','.');
            SaveDir=[strrep(CompiledDataTable.ExperimentsDir{1,1},'/Experiments/','/'),'GroupCompiles/',SaveFolderName];
            if ~exist(SaveDir,'file')
                mkdir(SaveDir);
            end
            save([SaveDir,SaveFileName],'CompiledDataTable');
            close(app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Title = uilabel(app.UIFigure);
            app.Title.Text = 'Compile Data';
            app.DatasetstocompilePanel = uipanel(app.UIFigure);
            app.DatasetstocompilePanel.Title = 'Datasets to compile';
            app.UITable = uitable(app.DatasetstocompilePanel);
            app.UITable.ColumnName = {'AnalysisType'; 'AnalysisName'; 'ParcelName'; 'LoadVarName'; 'DataType'; 'SubjectOrRun'; 'TableOrArray'; 'VertOrMat'; 'ExperimentsDir'; 'fmriprep_table_name'; 'ndims'; 'LoadVarFormat'};
            app.UITable.RowName = {};
            app.AddMoreButton = uibutton(app.DatasetstocompilePanel, 'push');
            app.AddMoreButton.ButtonPushedFcn = createCallbackFcn(app, @AddMoreButtonPushed, true);
            app.AddMoreButton.Enable = 'off';
            app.AddMoreButton.Text = 'Add to Table';
            app.EndButton = uibutton(app.DatasetstocompilePanel, 'push');
            app.EndButton.ButtonPushedFcn = createCallbackFcn(app, @EndButtonPushed, true);
            app.EndButton.Text = 'End';
            app.StartNewButton = uibutton(app.DatasetstocompilePanel, 'push');
            app.StartNewButton.ButtonPushedFcn = createCallbackFcn(app, @StartNewButtonPushed, true);
            app.StartNewButton.Text = 'Start New Set';
            app.SelectdirectoryandparticipanttablePanel = uipanel(app.UIFigure);
            app.SelectdirectoryandparticipanttablePanel.Title = '(1) Select directory and participant table';
            app.GetExperimentDirectoryButton = uibutton(app.SelectdirectoryandparticipanttablePanel, 'push');
            app.GetExperimentDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @GetExperimentDirectoryButtonPushed, true);
            app.GetExperimentDirectoryButton.Text = 'Get Experiment Directory';
            app.SelectExperimentTableButton = uibutton(app.SelectdirectoryandparticipanttablePanel, 'push');
            app.SelectExperimentTableButton.ButtonPushedFcn = createCallbackFcn(app, @SelectExperimentTableButtonPushed, true);
            app.SelectExperimentTableButton.Text = 'Select Experiment Table';
            app.ExpDirEditField = uieditfield(app.SelectdirectoryandparticipanttablePanel, 'text');
            app.ExpDirEditField.Editable = 'off';
            app.ExpTableName = uieditfield(app.SelectdirectoryandparticipanttablePanel, 'text');
            app.ExpTableName.Editable = 'off';
            app.ResetButton = uibutton(app.SelectdirectoryandparticipanttablePanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.SubjectorRunPanel = uipanel(app.UIFigure);
            app.SubjectorRunPanel.Title = '(2) Subject or Run';
            app.Switch = uiswitch(app.SubjectorRunPanel, 'toggle');
            app.Switch.Items = {'Run', 'Subject'};
            app.Switch.Value = 'Run';
            app.SetButton = uibutton(app.SubjectorRunPanel, 'push');
            app.SetButton.ButtonPushedFcn = createCallbackFcn(app, @SetButtonPushed, true);
            app.SetButton.Enable = 'off';
            app.SetButton.Text = 'Set';
            app.DefineDatasetsPanel = uipanel(app.UIFigure);
            app.DefineDatasetsPanel.Title = '(3) Define Datasets';
            app.TypeDropDownLabel = uilabel(app.DefineDatasetsPanel);
            app.TypeDropDownLabel.Enable = 'off';
            app.TypeDropDownLabel.Text = 'Type';
            app.TypeDropDown = uidropdown(app.DefineDatasetsPanel);
            app.TypeDropDown.Items = {'Undefined'};
            app.TypeDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeDropDownValueChanged, true);
            app.TypeDropDown.Enable = 'off';
            app.TypeDropDown.Value = 'Undefined';
            app.NameDropDownLabel = uilabel(app.DefineDatasetsPanel);
            app.NameDropDownLabel.Enable = 'off';
            app.NameDropDownLabel.Text = 'Name';
            app.NameDropDown = uidropdown(app.DefineDatasetsPanel);
            app.NameDropDown.Items = {'Undefined'};
            app.NameDropDown.ValueChangedFcn = createCallbackFcn(app, @NameDropDownValueChanged, true);
            app.NameDropDown.Enable = 'off';
            app.NameDropDown.Value = 'Undefined';
            app.ParcellationDropDownLabel = uilabel(app.DefineDatasetsPanel);
            app.ParcellationDropDownLabel.Enable = 'off';
            app.ParcellationDropDownLabel.Text = 'Parcellation';
            app.ParcellationDropDown = uidropdown(app.DefineDatasetsPanel);
            app.ParcellationDropDown.Items = {'Undefined'};
            app.ParcellationDropDown.ValueChangedFcn = createCallbackFcn(app, @ParcellationDropDownValueChanged, true);
            app.ParcellationDropDown.Enable = 'off';
            app.ParcellationDropDown.Value = 'Undefined';
            app.VariableDropDownLabel = uilabel(app.DefineDatasetsPanel);
            app.VariableDropDownLabel.Enable = 'off';
            app.VariableDropDownLabel.Text = 'Variable';
            app.VariableDropDown = uidropdown(app.DefineDatasetsPanel);
            app.VariableDropDown.Items = {'Undefined'};
            app.VariableDropDown.ValueChangedFcn = createCallbackFcn(app, @VariableDropDownValueChanged, true);
            app.VariableDropDown.Enable = 'off';
            app.VariableDropDown.Value = 'Undefined';
            app.SetDataParametersPanel = uipanel(app.UIFigure);
            app.SetDataParametersPanel.Title = '(4) Set Data Parameters';
            app.DataTypeDropDownLabel = uilabel(app.SetDataParametersPanel);
            app.DataTypeDropDownLabel.Enable = 'off';
            app.DataTypeDropDownLabel.Text = 'DataType';
            app.DataTypeDropDown = uidropdown(app.SetDataParametersPanel);
            app.DataTypeDropDown.Items = {'ByParcellation', 'ByCoordinate', 'Other'};
            app.DataTypeDropDown.Enable = 'off';
            app.DataTypeDropDown.Value = 'ByParcellation';
            app.LoadFormatDropDownLabel = uilabel(app.SetDataParametersPanel);
            app.LoadFormatDropDownLabel.Enable = 'off';
            app.LoadFormatDropDownLabel.Text = 'Load Format';
            app.LoadFormatDropDown = uidropdown(app.SetDataParametersPanel);
            app.LoadFormatDropDown.Items = {'Table', 'Array', 'Cell'};
            app.LoadFormatDropDown.Enable = 'off';
            app.LoadFormatDropDown.Value = 'Table';
            app.SaveTypeSwitchLabel = uilabel(app.SetDataParametersPanel);
            app.SaveTypeSwitchLabel.Enable = 'off';
            app.SaveTypeSwitchLabel.Text = 'Save Type';
            app.SaveTypeSwitch = uiswitch(app.SetDataParametersPanel, 'slider');
            app.SaveTypeSwitch.Items = {'Vertical', 'Matrix'};
            app.SaveTypeSwitch.Enable = 'off';
            app.SaveTypeSwitch.Value = 'Vertical';
            app.SaveFormatSwitch_2Label = uilabel(app.SetDataParametersPanel);
            app.SaveFormatSwitch_2Label.Enable = 'off';
            app.SaveFormatSwitch_2Label.Text = 'Save Format';
            app.SaveFormatSwitch_2 = uiswitch(app.SetDataParametersPanel, 'slider');
            app.SaveFormatSwitch_2.Items = {'Table', 'Array'};
            app.SaveFormatSwitch_2.Enable = 'off';
            app.SaveFormatSwitch_2.Value = 'Table';
            app.TableRow = uispinner(app.UIFigure);
            app.TableRow.Editable = 'off';
            app.TableRow.Enable = 'off';
            app.TableRow.Visible = 'off';
            app.TableRow.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_CompileData

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end